classdef axesWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TitleLabel      matlab.ui.control.Label
        CloseButton     matlab.ui.control.Button
        SaveButton      matlab.ui.control.Button
        MainAxesWindow  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MainAppButton
        XValues
        YValues
        XLabel
        YLabel
        PlotTitle
        WindowTitle
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAppButton, xValues, yValues, xLabel, yLabel, plotTitle, windowTitle)
            app.MainAppButton = mainAppButton;
            app.XValues = xValues;
            app.YValues = yValues;
            app.XLabel = xLabel;
            app.YLabel = yLabel;
            app.PlotTitle = plotTitle;
            app.WindowTitle = windowTitle;
            
            app.TitleLabel.Text = app.WindowTitle;
            app.MainAxesWindow.Title.String = app.PlotTitle;
            app.MainAxesWindow.XLabel.String = app.XLabel;
            app.MainAxesWindow.YLabel.String = app.YLabel;
            plot(app.MainAxesWindow, app.XValues, app.YValues, 'LineWidth', 2);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.MainAppButton.Enable = 'On';
            catch buttonException
                % We should catch all the exceptions related to button enabling action
                % just not to have the situation when window cannot be closed
            end
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.MainAppButton.Enable = 'On';
            catch buttonException
                % We should catch all the exceptions related to button enabling action
                % just not to have the situation when window cannot be closed
            end
            delete(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            csvMatrix = [app.XValues, app.YValues];
            
            [file, path] = uiputfile('result.csv', 'Save result');
            filename = fullfile(path, file);
            if file ~= 0
                cHeader = {app.XLabel, app.YLabel};
                commaHeader = [cHeader;repmat({','},1,numel(cHeader))]; 
                commaHeader = commaHeader(:)';
                textHeader = cell2mat(commaHeader); 

                fid = fopen(filename,'w'); 
                fprintf(fid,'%s\n',textHeader);
                fclose(fid);
                %write data to end of file
                dlmwrite(filename, csvMatrix,'-append'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MainAxesWindow = uiaxes(app.UIFigure);
            title(app.MainAxesWindow, 'Response')
            xlabel(app.MainAxesWindow, 'x label')
            ylabel(app.MainAxesWindow, 'y label')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = axesWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end