classdef geneticAlgorithmParametersSearchWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GeneticalgorithmparameterssearchLabel  matlab.ui.control.Label
        StartParametersSearchButton   matlab.ui.control.Button
        NelderMeadSetupPanel          matlab.ui.container.Panel
        UseCustomInitializationCodeCheckBox  matlab.ui.control.CheckBox
        MaxgenerationsLabel           matlab.ui.control.Label
        MaxgenerationsEditField       matlab.ui.control.NumericEditField
        MaxstallgenerationsLabel      matlab.ui.control.Label
        MaxstallgenerationsEditField  matlab.ui.control.NumericEditField
        MaxstalltimeLabel             matlab.ui.control.Label
        MaxstalltimeEditField         matlab.ui.control.NumericEditField
        MaxtimeLabel                  matlab.ui.control.Label
        MaxtimeEditField              matlab.ui.control.NumericEditField
        FunctiontoleranceLabel        matlab.ui.control.Label
        FunctiontoleranceEditField    matlab.ui.control.NumericEditField
        FitnesslimitLabel             matlab.ui.control.Label
        FitnesslimitEditField         matlab.ui.control.NumericEditField
        CustomInitializationCodeEditField  matlab.ui.control.EditField
        CustomoptionsObjectLabel2     matlab.ui.control.Label
        CustomoptionsObjectLabel3     matlab.ui.control.Label
        CustomoptionsObjectLabel5     matlab.ui.control.Label
        CustomoptionsObjectLabel6     matlab.ui.control.Label
        CustomoptionsObjectLabel4     matlab.ui.control.Label
        LeaveEmptyLabel               matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        StopParametersSearchButton    matlab.ui.control.Button
        MinimizedObjectiveFunctionInfoButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ClosedLoopSystemSetupObject
        ControllerSetupObject
        ControllerParameters
        ObjectiveFunctionSetupObject
        Kp
        Ki
        Kd
        IntegralParametersVector
        DerivativeParametersVector
        StopParameterSearchingFlag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject)
            app.ClosedLoopSystemSetupObject = closedLoopSystemSetupObject;
            app.ControllerSetupObject = controllerSetupObject;
            app.ControllerParameters = controllerParameters;
            app.ObjectiveFunctionSetupObject = objectiveFunctionSetupObject;
            
            % Initialize properties
            app.Kp = app.ControllerParameters.Kp;
            app.Ki = app.ControllerParameters.Ki;
            app.Kd = app.ControllerParameters.Kd;
            app.IntegralParametersVector = app.ControllerParameters.IntegralParametersVector;
            app.DerivativeParametersVector = app.ControllerParameters.DerivativeParametersVector;

            % Set the flag which indicates if parameters searching should be stopped
            app.StopParameterSearchingFlag = false;
        end

        % Value changed function: 
        % UseCustomInitializationCodeCheckBox
        function UseCustomInitializationCodeCheckBoxValueChanged(app, event)
            value = app.UseCustomInitializationCodeCheckBox.Value;
            
            if ( value )
                app.PopulationsizeEditField.Enable = 'Off';
                app.MaxgenerationsEditField.Enable = 'Off';
                app.MaxstallgenerationsEditField.Enable = 'Off';
                app.MaxstalltimeEditField.Enable = 'Off';
                app.MaxtimeEditField.Enable = 'Off';
                app.MaxtimeEditField.Enable = 'Off';
                app.FunctiontoleranceEditField.Enable = 'Off';
                app.FitnesslimitEditField.Enable = 'Off';
                
                app.CustomInitializationCodeEditField.Enable = 'On';
            else
                app.PopulationsizeEditField.Enable = 'On';
                app.MaxgenerationsEditField.Enable = 'On';
                app.MaxstallgenerationsEditField.Enable = 'On';
                app.MaxstalltimeEditField.Enable = 'On';
                app.MaxtimeEditField.Enable = 'On';
                app.MaxtimeEditField.Enable = 'On';
                app.FunctiontoleranceEditField.Enable = 'On';
                app.FitnesslimitEditField.Enable = 'On';
                
                app.CustomInitializationCodeEditField.Enable = 'Off';
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartParametersSearchButton
        function StartParametersSearchButtonPushed(app, event)
            % Initialize model
            modelName = fvopidToolbox.app.utils.initializeModel(app.ClosedLoopSystemSetupObject, app.ControllerSetupObject);
            
            % Set objective function handler
            objectiveFunctionHandler = fvopidToolbox.app.utils.getObjectiveFunctionHandler(app.ObjectiveFunctionSetupObject);
            
            % Set a handler to function which evaluates objective funtion value
            numberOfIntegralOrderParameters = length(app.IntegralParametersVector);
            numberOfDerivativeOrderParameters = length(app.IntegralParametersVector);
            simulationTimeString = num2str(app.ClosedLoopSystemSetupObject.SimulationTime);
            setpointValue = app.ClosedLoopSystemSetupObject.SetpointValue;
            objectiveFunctionValueHandler = @(parametersVector)fvopidToolbox.app.optimization.getObjectiveFunctionValue(modelName, numberOfIntegralOrderParameters,...
                simulationTimeString, setpointValue, objectiveFunctionHandler, parametersVector);
            
            % Create options object for optimization
            customInitializationCode = app.UseCustomInitializationCodeCheckBox.Value;
            if ( customInitializationCode )
                options = eval(app.CustomInitializationCodeEditField.Value);
            else
                options = optimoptions('ga');
                
                maxGenerations = app.MaxgenerationsEditField.Value;
                maxStallGenerations = app.MaxstallgenerationsEditField.Value;
                maxStallTime = app.MaxstalltimeEditField.Value;
                maxTime = app.MaxtimeEditField.Value;
                functionTolerance = app.FunctiontoleranceEditField.Value;
                fitnessLimit = app.FitnesslimitEditField.Value;
                
                if maxGenerations ~= 0
                    options.MaxGenerations = maxGenerations;
                end 
                
                if maxStallGenerations ~= 0
                    options.MaxStallGenerations = maxStallGenerations;
                end  
                                
                if maxStallTime ~= 0
                    options.MaxStallTime = maxStallTime;
                end
                
                if maxTime ~= 0
                    options.MaxTime = maxTime;
                end
                
                if functionTolerance ~= 0
                    options.FunctionTolerance = functionTolerance;
                end
                
                if fitnessLimit ~= 0
                    options.FitnessLimit = fitnessLimit;
                end
            end
            
            app.StartParametersSearchButton.Enable = 'Off';
            app.StopParametersSearchButton.Enable = 'On';
            
            % Run searching process
            optimizationConvergenceIterationVector = [];
            optimizationConvergenceObjectiveFunctionVector = [];
            progressFigure = uifigure;
            progressDialog = uiprogressdlg(progressFigure, 'Title', 'Please wait', 'Message', "Running optimization oprocess..."); 
            
            % Set the function which monitors progress of the optimization process
            % The function is embedded to use external variables to communicate with the application
            function [state, options, optchanged] = monitorProgressFunction(inputOptions, inputState, flag)
                state = inputState;
                if app.StopParameterSearchingFlag
                    state.StopFlag = 'y';
                end
                if ~isempty(state.Best)
                    progressDialog.Message = ["Running optimization oprocess...",... 
                        strcat("Current generation number: ", num2str(state.Generation)),...
                        strcat("Best objective function value: ", num2str(state.Best(end)))];
                else
                    progressDialog.Message = ["Running optimization oprocess...",... 
                        strcat("Current generation number: ", num2str(state.Generation))];
                end
                
                if ~isempty(state.Best)
                    optimizationConvergenceIterationVector = [optimizationConvergenceIterationVector state.Generation];
                    optimizationConvergenceObjectiveFunctionVector = [optimizationConvergenceObjectiveFunctionVector state.Best(end)];
                end
                
                options = inputOptions;
                optchanged = false;
            end
            options.OutputFcn = @monitorProgressFunction;
            
            try 
                nvars = 3 + numberOfIntegralOrderParameters + numberOfDerivativeOrderParameters;
                set_param(modelName,'FastRestart','on')
                [resultParameters, FVAL, EXITFLAG, OUTPUT] = ga(objectiveFunctionValueHandler, nvars, [], [], [], [], [], [], [], options);
                set_param(modelName,'FastRestart','off')
    
                parametersSearchingStopped = app.StopParameterSearchingFlag;
                app.StopParameterSearchingFlag = false;
                
                % Create object which stores controller parameters
                optimizedControllerParametersObject = fvopidToolbox.app.utils.ControllerParameters;
                optimizedControllerParametersObject.Kp = resultParameters(1);
                optimizedControllerParametersObject.Ki = resultParameters(2);
                optimizedControllerParametersObject.Kd = resultParameters(3);
                optimizedControllerParametersObject.IntegralParametersVector = resultParameters(4:3 + numberOfIntegralOrderParameters);
                optimizedControllerParametersObject.DerivativeParametersVector = resultParameters(4 + numberOfIntegralOrderParameters:end);
                
                % Open window which shows the optimization results
                convergenceMatrix = [optimizationConvergenceIterationVector; optimizationConvergenceObjectiveFunctionVector];
                algorithmExecutionDetails = [strcat("Objective function value:    ", num2str(FVAL)),...
                    " ",...
                    strcat("Exit flag:    ", num2str(EXITFLAG)),...
                    strcat("Number of generations:   ", num2str(OUTPUT.generations)),...
                    strcat("Number of function evaluations:   ", num2str(OUTPUT.funccount)),...
                    strcat("Algorithm:   ", "Genetic Algorithm"),...
                    strcat("Exit message:   ", num2str(OUTPUT.message))];
                fvopidToolbox.app.optimizationResultsWindow("Genetic Algorithm parameters searching results", app.ClosedLoopSystemSetupObject, app.ControllerSetupObject, optimizedControllerParametersObject, parametersSearchingStopped, algorithmExecutionDetails, convergenceMatrix);
                
                % Turn off progress dialog  
                close(progressFigure);
                
                % Close system
                close_system(modelName, false);
            catch Ex
                set_param(modelName,'FastRestart','off')
                % Turn off progress dialog
                close(progressFigure);
                
                % Close system
                close_system(modelName, false);
                
                % Set the buttons
                app.StartParametersSearchButton.Enable = 'On';
                app.StopParametersSearchButton.Enable = 'Off';
                
                rethrow(Ex);
            end
            
            % Set the buttons
            app.StartParametersSearchButton.Enable = 'On';
            app.StopParametersSearchButton.Enable = 'Off';
        end

        % Button pushed function: StopParametersSearchButton
        function StopParametersSearchButtonPushed(app, event)
            app.StopParameterSearchingFlag = true;
        end

        % Button pushed function: 
        % MinimizedObjectiveFunctionInfoButton
        function MinimizedObjectiveFunctionInfoButtonPushed(app, event)
            app.MinimizedObjectiveFunctionInfoButton.Enable = 'off';
            fvopidToolbox.app.infoGeneticAlgorithmOptimizationWindow(app, app.MinimizedObjectiveFunctionInfoButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GeneticalgorithmparameterssearchLabel = uilabel(app.UIFigure);
            app.GeneticalgorithmparameterssearchLabel.Text = 'Genetic algorithm parameters search';
            app.StartParametersSearchButton = uibutton(app.UIFigure, 'push');
            app.StartParametersSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StartParametersSearchButtonPushed, true);
            app.StartParametersSearchButton.Text = 'Start parameters search';
            app.NelderMeadSetupPanel = uipanel(app.UIFigure);
            app.NelderMeadSetupPanel.Title = 'Genetic algorithm setup';
            app.UseCustomInitializationCodeCheckBox = uicheckbox(app.NelderMeadSetupPanel);
            app.UseCustomInitializationCodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCustomInitializationCodeCheckBoxValueChanged, true);
            app.UseCustomInitializationCodeCheckBox.Text = 'Use custom initialization code';
            app.MaxgenerationsLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxgenerationsLabel.Text = 'Max generations*:';
            app.MaxgenerationsEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxstallgenerationsLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxstallgenerationsLabel.Text = 'Max stall generations*:';
            app.MaxstallgenerationsEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxstalltimeLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxstalltimeLabel.Text = 'Max stall time*:';
            app.MaxstalltimeEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxtimeLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxtimeLabel.Text = 'Max time*:';
            app.MaxtimeEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.FunctiontoleranceLabel = uilabel(app.NelderMeadSetupPanel);
            app.FunctiontoleranceLabel.Text = 'Function tolerance*:';
            app.FunctiontoleranceEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.FitnesslimitLabel = uilabel(app.NelderMeadSetupPanel);
            app.FitnesslimitLabel.Text = 'Fitness limit*:';
            app.FitnesslimitEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.CustomInitializationCodeEditField = uieditfield(app.NelderMeadSetupPanel, 'text');
            app.CustomInitializationCodeEditField.Enable = 'off';
            app.CustomoptionsObjectLabel2 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel2.Enable = 'off';
            app.CustomoptionsObjectLabel2.Text = 'Provide custom ''optimoptions'' object which should be used to set optimization options of Genetic algorithm';
            app.CustomoptionsObjectLabel3 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel3.Enable = 'off';
            app.CustomoptionsObjectLabel3.Text = 'such object, or use ''optimset'' function to generate one, e.g.:';
            app.CustomoptionsObjectLabel5 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel5.Enable = 'off';
            app.CustomoptionsObjectLabel5.Text = 'For more details check regarding ''optimoptions'' object check ''ga'' function MathWorks support page.';
            app.CustomoptionsObjectLabel6 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel6.Enable = 'off';
            app.CustomoptionsObjectLabel6.Text = 'implemented by Matlab''s ga function. You can provide your own custom function invocation to return ';
            app.CustomoptionsObjectLabel4 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel4.Enable = 'off';
            app.CustomoptionsObjectLabel4.Text = 'optimoptions(''particleswarm'', ''SwarmSize'', 100).';
            app.LeaveEmptyLabel = uilabel(app.NelderMeadSetupPanel);
            app.LeaveEmptyLabel.Text = '*Set to 0 to use the default property value';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.StopParametersSearchButton = uibutton(app.UIFigure, 'push');
            app.StopParametersSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StopParametersSearchButtonPushed, true);
            app.StopParametersSearchButton.Enable = 'off';
            app.StopParametersSearchButton.Text = 'Stop parameters search';
            app.MinimizedObjectiveFunctionInfoButton = uibutton(app.UIFigure, 'push');
            app.MinimizedObjectiveFunctionInfoButton.ButtonPushedFcn = createCallbackFcn(app, @MinimizedObjectiveFunctionInfoButtonPushed, true);
            app.MinimizedObjectiveFunctionInfoButton.Icon = 'information.png';
            app.MinimizedObjectiveFunctionInfoButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = geneticAlgorithmParametersSearchWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end