classdef infoFVOPIDWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TitleLabel         matlab.ui.control.Label
        ContentTextArea    matlab.ui.control.TextArea
        CloseButton        matlab.ui.control.Button
        PresstoseeFVOPIDTypeAdefinitionButton  matlab.ui.control.Button
        PresstoseeFVOPIDConvolutionTypedefinitionButton  matlab.ui.control.Button
        ContentTextArea_2  matlab.ui.control.TextArea
    end


    properties (Access = private)
        CallingApp
        MainAppButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mainAppButton)
            app.CallingApp = mainapp;
            app.MainAppButton = mainAppButton;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: 
        % PresstoseeFVOPIDTypeAdefinitionButton
        function PresstoseeFVOPIDTypeAdefinitionButtonPushed(app, event)
            app.PresstoseeFVOPIDTypeAdefinitionButton.Enable = 'off';
            fvopidToolbox.app.infoFVOPIDTypeADefinitionWindow(app, app.PresstoseeFVOPIDTypeAdefinitionButton);
        end

        % Button pushed function: 
        % PresstoseeFVOPIDConvolutionTypedefinitionButton
        function PresstoseeFVOPIDConvolutionTypedefinitionButtonPushed(app, event)
            app.PresstoseeFVOPIDConvolutionTypedefinitionButton.Enable = 'off';
            fvopidToolbox.app.infoFVOPIDConvolutionTypeDefinitionWindow(app, app.PresstoseeFVOPIDConvolutionTypedefinitionButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Controller basic parameters';
            app.ContentTextArea = uitextarea(app.UIFigure);
            app.ContentTextArea.Editable = 'off';
            app.ContentTextArea.Value = {'''Controller basic parameters'' contains 5 input fields which determine basic setup of the controller which is used in the simulated closed loop system. The setup includes:'; '1) ''Controller type'' - determines the type of FVOPID controller. By the type of the controller we understand the version of Grünwald–Letnikov definition used to calculate controller''s integral (summation) and derivative (difference) gains. Currently two controller types are avialbe which is FVOPID Type A and FVOPID Convolution Type.'};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.PresstoseeFVOPIDTypeAdefinitionButton = uibutton(app.UIFigure, 'push');
            app.PresstoseeFVOPIDTypeAdefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoseeFVOPIDTypeAdefinitionButtonPushed, true);
            app.PresstoseeFVOPIDTypeAdefinitionButton.Text = 'Press to see FVOPID Type A definition';
            app.PresstoseeFVOPIDConvolutionTypedefinitionButton = uibutton(app.UIFigure, 'push');
            app.PresstoseeFVOPIDConvolutionTypedefinitionButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoseeFVOPIDConvolutionTypedefinitionButtonPushed, true);
            app.PresstoseeFVOPIDConvolutionTypedefinitionButton.Text = 'Press to see FVOPID Convolution Type definition';
            app.ContentTextArea_2 = uitextarea(app.UIFigure);
            app.ContentTextArea_2.Editable = 'off';
            app.ContentTextArea_2.Value = {'2) ''Proportinal (P)'' - value of the proportional gain of the controller.'; '3) ''Integral (I)'' - value of the integral (summation) gain of the controller.'; '4) ''Derivative (D)'' - value of the derivative(difference) gain of the controller.'; '5) ''Controller sampling time [s]'' - sampling time of the controller (the tool supports only discrete controllers implementation).'; ''; 'The setup of controller''s order can be found in ''Integral order'' and ''Derivative order'' tabs. In ''Controller type'' there are only two FVOPID related options available but the simulation of constant order FOPID or even PID is also supported by simply setting a proper value of orders in ''Integral order'' and ''Derivative order'' tabs. For example setting both orders as constant real values would result in FOPID controller, while setting both orders as 1 will result in PID controller.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = infoFVOPIDWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end