classdef infoPlantWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TitleLabel              matlab.ui.control.Label
        ContentTextArea         matlab.ui.control.TextArea
        CloseButton             matlab.ui.control.Button
        SeePlantExamplesButton  matlab.ui.control.Button
        ContentTextArea_2       matlab.ui.control.TextArea
    end


    properties (Access = private)
        CallingApp
        MainAppButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mainAppButton)
            app.CallingApp = mainapp;
            app.MainAppButton = mainAppButton;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: SeePlantExamplesButton
        function SeePlantExamplesButtonPushed(app, event)
            app.SeePlantExamplesButton.Enable = 'off';
            fvopidToolbox.app.infoPlantExampleWindow(app, app.SeePlantExamplesButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Plant & simulation setup';
            app.ContentTextArea = uitextarea(app.UIFigure);
            app.ContentTextArea.Editable = 'off';
            app.ContentTextArea.Value = {'''Plant & simulation setup'' section is used the set the main patrameters of the closed loop system. The main inputs are:'; '1) ''Discrete Plant'' - when selected the plant is set as discrete. In this case Simulink ''Discrete Transfer fcn'' block is used as a plant where Numerator and Denominator parameters are set accoring to he values provided in ''Plant transfer function numerator'' and ''Plant transfer function denominator'' inputs.'; '2) ''Discrete plant sample time [s]'' - the input is enabled only when ''Discrete Plant'' option is selected. The value from this field is set as ''Sample time'' parameter of Discrete Transfer Fcn block in Simulink.'; '3) ''Plant transfer function numerator'' - contains the numerator values of the plant. For continous plant it is the numerator of s-transfer function, while for discrete plant it is the numerator of z-transfer function.'; '4) ''Plant transfer function denominator'' - contains the denominator values of the plant. For continous plant it is the denominator of s-transfer function, while for discrete plant it is the denominator of z-transfer function.'};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SeePlantExamplesButton = uibutton(app.UIFigure, 'push');
            app.SeePlantExamplesButton.ButtonPushedFcn = createCallbackFcn(app, @SeePlantExamplesButtonPushed, true);
            app.SeePlantExamplesButton.Text = 'See plant examples';
            app.ContentTextArea_2 = uitextarea(app.UIFigure);
            app.ContentTextArea_2.Editable = 'off';
            app.ContentTextArea_2.Value = {'5) ''Closed loop system setpoint value'' - value of the setpoint.'; '6) ''Simulation time [s]'' - time of the simulation. ''Simulation time'' value divided by ''Controller sampling time'' cannot exeed 5000 when parameters searching process is started. In the the other words the tool does not allow parameters searching where there are more than 5000 samples processed by the controller by simulation (because of the performance - this will be improved in the future toolbox release).'; '7) ''Generated output sampling time [s]'' - sampling time of the simulation results signals.'; '8) ''Transport delay [s]'' - when the value in this input field is greater than 0, the Simulink Transport Delay block is added to the system. The block has ''Time delay'' parameter set with value from this input.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = infoPlantWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end