classdef informationWindowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TitleLabel       matlab.ui.control.Label
        ContentTextArea  matlab.ui.control.TextArea
        CloseButton      matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp
        MainAppButton
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mainAppButton, informationTitle, informationText)
            app.CallingApp = mainapp;
            app.MainAppButton = mainAppButton;
            app.TitleLabel.Text = informationTitle;
            app.ContentTextArea.Value = informationText;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title label';
            app.ContentTextArea = uitextarea(app.UIFigure);
            app.ContentTextArea.Editable = 'off';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = informationWindowApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end