classdef nelderMeadParametersSearchWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NelderMeadparameterssearchLabel  matlab.ui.control.Label
        StartParametersSearchButton     matlab.ui.control.Button
        NelderMeadSetupPanel            matlab.ui.container.Panel
        InitialvaluesofintegralorderparametersEditFieldLabel  matlab.ui.control.Label
        InitialvaluesofintegralorderparametersEditField  matlab.ui.control.EditField
        InitialvaluesofderivativeorderparametersEditFieldLabel  matlab.ui.control.Label
        InitialvaluesofderivativeorderparametersEditField  matlab.ui.control.EditField
        UseCustomInitializationCodeCheckBox  matlab.ui.control.CheckBox
        InitialKpEditFieldLabel         matlab.ui.control.Label
        InitialKpEditField              matlab.ui.control.NumericEditField
        InitialKiEditFieldLabel         matlab.ui.control.Label
        InitialKiEditField              matlab.ui.control.NumericEditField
        InitialKdEditFieldLabel         matlab.ui.control.Label
        InitialKdEditField              matlab.ui.control.NumericEditField
        CustomOptionsObjectEditField    matlab.ui.control.EditField
        CustomoptionsObjectLabel1       matlab.ui.control.Label
        CustomoptionsObjectLabel2       matlab.ui.control.Label
        CustomoptionsObjectLabel3       matlab.ui.control.Label
        CustomoptionsObjectLabel4       matlab.ui.control.Label
        CustomoptionsObjectLabel5       matlab.ui.control.Label
        CustomoptionsObjectLabel6       matlab.ui.control.Label
        MaxfunctionevaluationsEditField_2Label  matlab.ui.control.Label
        MaxfunctionevaluationsEditField  matlab.ui.control.NumericEditField
        MaxnumberofiterationsEditField_2Label  matlab.ui.control.Label
        MaxnumberofiterationsEditField  matlab.ui.control.NumericEditField
        TolFunEditField_2Label          matlab.ui.control.Label
        TolFunEditField                 matlab.ui.control.NumericEditField
        TolXEditField_2Label            matlab.ui.control.Label
        TolXEditField                   matlab.ui.control.NumericEditField
        LeaveEmptyLabel                 matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        MinimizedObjectiveFunctionInfoButton  matlab.ui.control.Button
        StopParametersSearchButton      matlab.ui.control.Button
    end


    properties (Access = private)
        ClosedLoopSystemSetupObject
        ControllerSetupObject
        ControllerParameters
        ObjectiveFunctionSetupObject
        Kp
        Ki
        Kd
        IntegralParametersVector
        DerivativeParametersVector
        StopParameterSearchingFlag
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject)
            app.ClosedLoopSystemSetupObject = closedLoopSystemSetupObject;
            app.ControllerSetupObject = controllerSetupObject;
            app.ControllerParameters = controllerParameters;
            app.ObjectiveFunctionSetupObject = objectiveFunctionSetupObject;
            
            % Initialize properties
            app.Kp = app.ControllerParameters.Kp;
            app.Ki = app.ControllerParameters.Ki;
            app.Kd = app.ControllerParameters.Kd;
            app.IntegralParametersVector = app.ControllerParameters.IntegralParametersVector;
            app.DerivativeParametersVector = app.ControllerParameters.DerivativeParametersVector;
            
            % Set initial input values
            app.InitialKpEditField.Value = app.ControllerParameters.Kp;
            app.InitialKiEditField.Value = app.ControllerParameters.Ki;
            app.InitialKdEditField.Value = app.ControllerParameters.Kd;
            app.InitialvaluesofintegralorderparametersEditField.Value = ['[' num2str(app.ControllerParameters.IntegralParametersVector) ']'];
            app.InitialvaluesofderivativeorderparametersEditField.Value = ['[' num2str(app.ControllerParameters.DerivativeParametersVector) ']'];
            
            % Set the flag which indicates if parameters searching should be stopped
            app.StopParameterSearchingFlag = false;
        end

        % Value changed function: 
        % UseCustomInitializationCodeCheckBox
        function UseCustomInitializationCodeCheckBoxValueChanged(app, event)
            value = app.UseCustomInitializationCodeCheckBox.Value;
            
            if ( value )
                app.CustomOptionsObjectEditField.Enable = 'On';
                app.CustomoptionsObjectLabel1.Enable = 'On';
                app.CustomoptionsObjectLabel2.Enable = 'On';
                app.CustomoptionsObjectLabel3.Enable = 'On';
                app.CustomoptionsObjectLabel4.Enable = 'On';
                app.CustomoptionsObjectLabel5.Enable = 'On';
                app.CustomoptionsObjectLabel6.Enable = 'On';
                
                app.MaxfunctionevaluationsEditField.Enable = 'Off';
                app.MaxnumberofiterationsEditField.Enable = 'Off';
                app.TolFunEditField.Enable = 'Off';
                app.TolXEditField.Enable = 'Off';
            else
                app.CustomOptionsObjectEditField.Enable = 'Off';
                app.CustomoptionsObjectLabel1.Enable = 'Off';
                app.CustomoptionsObjectLabel2.Enable = 'Off';
                app.CustomoptionsObjectLabel3.Enable = 'Off';
                app.CustomoptionsObjectLabel4.Enable = 'Off';
                app.CustomoptionsObjectLabel5.Enable = 'Off';
                app.CustomoptionsObjectLabel6.Enable = 'Off';
                
                app.MaxfunctionevaluationsEditField.Enable = 'On';
                app.MaxnumberofiterationsEditField.Enable = 'On';
                app.TolFunEditField.Enable = 'On';
                app.TolXEditField.Enable = 'On';
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartParametersSearchButton
        function StartParametersSearchButtonPushed(app, event)
            
            % Initialize model
            modelName = fvopidToolbox.app.utils.initializeModel(app.ClosedLoopSystemSetupObject, app.ControllerSetupObject);
            
            % Set objective function handler
            objectiveFunctionHandler = fvopidToolbox.app.utils.getObjectiveFunctionHandler(app.ObjectiveFunctionSetupObject);
            
            % Set optimization starting point
            x0 = [app.InitialKpEditField.Value, app.InitialKiEditField.Value, app.InitialKdEditField.Value,...
                str2num(app.InitialvaluesofintegralorderparametersEditField.Value), str2num(app.InitialvaluesofderivativeorderparametersEditField.Value)];
            
            % Set a handler to function which evaluates objective funtion value
            numberOfIntegralOrderParameters = length(app.IntegralParametersVector);
            simulationTimeString = num2str(app.ClosedLoopSystemSetupObject.SimulationTime);
            setpointValue = app.ClosedLoopSystemSetupObject.SetpointValue;
            objectiveFunctionValueHandler = @(parametersVector)fvopidToolbox.app.optimization.getObjectiveFunctionValue(modelName, numberOfIntegralOrderParameters,...
                simulationTimeString, setpointValue, objectiveFunctionHandler, parametersVector);
            
            % Create options object for optimization
            customInitializationCode = app.UseCustomInitializationCodeCheckBox.Value;
            if ( customInitializationCode )
                options = eval(app.CustomOptionsObjectEditField.Value);
            else
                options = optimset();
                
                maxFunctionEvaluationsValue = app.MaxfunctionevaluationsEditField.Value;
                maxNumberOfIterationsValue = app.MaxnumberofiterationsEditField.Value;
                tolFunValue = app.TolFunEditField.Value;
                tolXValue = app.TolXEditField.Value;
                
                if maxFunctionEvaluationsValue ~= 0
                    options.MaxFunEvals = maxFunctionEvaluationsValue;
                end
                
                if maxNumberOfIterationsValue ~= 0
                    options.MaxIter = maxNumberOfIterationsValue;
                end
                                
                if tolFunValue ~= 0
                    options.TolFun = maxFunctitolFunValueonEvaluationsValue;
                end
                                                
                if tolXValue ~= 0
                    options.TolX = tolXValue;
                end
            end
            
            app.StartParametersSearchButton.Enable = 'Off';
            app.StopParametersSearchButton.Enable = 'On';
            
            % Run searching process
            optimizationConvergenceIterationVector = [];
            optimizationConvergenceObjectiveFunctionVector = [];
            progressFigure = uifigure;
            progressDialog = uiprogressdlg(progressFigure, 'Title', 'Please wait', 'Message', "Running optimization oprocess...");        
            
            % Set the function which monitors progress of the optimization process
            % The function is embedded to use external variables to communicate with the application
            function stop = monitorProgressFunction(x, optimValues, state)
                if app.StopParameterSearchingFlag
                    stop = true;
                else 
                    stop = false;
                end
                
                progressDialog.Message = ["Running optimization oprocess...",... 
                    strcat("Number of algorithm iteration: ", num2str(optimValues.iteration)),...
                    strcat("Objective function value: ", num2str(optimValues.fval))];
                
                optimizationConvergenceIterationVector = [optimizationConvergenceIterationVector optimValues.iteration];
                optimizationConvergenceObjectiveFunctionVector = [optimizationConvergenceObjectiveFunctionVector optimValues.fval];
            end
            options.OutputFcn = @monitorProgressFunction;
            
            try
                set_param(modelName,'FastRestart','on')
                [resultParameters, FVAL, EXITFLAG, OUTPUT] = fminsearch(objectiveFunctionValueHandler, x0, options);
                set_param(modelName,'FastRestart','off')
    
                parametersSearchingStopped = app.StopParameterSearchingFlag;
                app.StopParameterSearchingFlag = false;
                
                % Create object which stores controller parameters
                optimizedControllerParametersObject = fvopidToolbox.app.utils.ControllerParameters;
                optimizedControllerParametersObject.Kp = resultParameters(1);
                optimizedControllerParametersObject.Ki = resultParameters(2);
                optimizedControllerParametersObject.Kd = resultParameters(3);
                optimizedControllerParametersObject.IntegralParametersVector = resultParameters(4:3 + numberOfIntegralOrderParameters);
                optimizedControllerParametersObject.DerivativeParametersVector = resultParameters(4 + numberOfIntegralOrderParameters:end);
                
                % Open window which shows the optimization results
                convergenceMatrix = [optimizationConvergenceIterationVector; optimizationConvergenceObjectiveFunctionVector];
                algorithmExecutionDetails = [strcat("Objective function value:    ", num2str(FVAL)),...
                    " ",...
                    strcat("Exit flag:    ", num2str(EXITFLAG)),...
                    strcat("Number of iterations:   ", num2str(OUTPUT.iterations)),...
                    strcat("Number of function evaluations:   ", num2str(OUTPUT.funcCount)),...
                    strcat("Algorithm:   ", num2str(OUTPUT.algorithm)),...
                    strcat("Exit message:   ", num2str(OUTPUT.message))];
                fvopidToolbox.app.optimizationResultsWindow("Nelder-Mead parameters searching results", app.ClosedLoopSystemSetupObject, app.ControllerSetupObject, optimizedControllerParametersObject, parametersSearchingStopped, algorithmExecutionDetails, convergenceMatrix);
                
                % Turn off progress dialog  
                close(progressFigure);
                
                % Close system
                close_system(modelName, false);
            catch Ex
                set_param(modelName,'FastRestart','off')
                
                % Turn off progress dialog
                close(progressFigure);
                
                % Close system
                close_system(modelName, false);
                
                % Set the buttons
                app.StartParametersSearchButton.Enable = 'On';
                app.StopParametersSearchButton.Enable = 'Off';
                
                rethrow(Ex);
            end
            
            % Set the buttons
            app.StartParametersSearchButton.Enable = 'On';
            app.StopParametersSearchButton.Enable = 'Off';
        end

        % Button pushed function: StopParametersSearchButton
        function StopParametersSearchButtonPushed(app, event)
            app.StopParameterSearchingFlag = true;
        end

        % Button pushed function: 
        % MinimizedObjectiveFunctionInfoButton
        function MinimizedObjectiveFunctionInfoButtonPushed(app, event)
            app.MinimizedObjectiveFunctionInfoButton.Enable = 'off';
            fvopidToolbox.app.infoNelderMeadOptimizationWindow(app, app.MinimizedObjectiveFunctionInfoButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NelderMeadparameterssearchLabel = uilabel(app.UIFigure);
            app.NelderMeadparameterssearchLabel.Text = 'Nelder-Mead parameters search';
            app.StartParametersSearchButton = uibutton(app.UIFigure, 'push');
            app.StartParametersSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StartParametersSearchButtonPushed, true);
            app.StartParametersSearchButton.Text = 'Start parameters search';
            app.NelderMeadSetupPanel = uipanel(app.UIFigure);
            app.NelderMeadSetupPanel.Title = 'Nelder-Mead setup';
            app.InitialvaluesofintegralorderparametersEditFieldLabel = uilabel(app.NelderMeadSetupPanel);
            app.InitialvaluesofintegralorderparametersEditFieldLabel.Text = 'Initial values of integral order parameters:';
            app.InitialvaluesofintegralorderparametersEditField = uieditfield(app.NelderMeadSetupPanel, 'text');
            app.InitialvaluesofderivativeorderparametersEditFieldLabel = uilabel(app.NelderMeadSetupPanel);
            app.InitialvaluesofderivativeorderparametersEditFieldLabel.Text = 'Initial values of derivative order parameters:';
            app.InitialvaluesofderivativeorderparametersEditField = uieditfield(app.NelderMeadSetupPanel, 'text');
            app.UseCustomInitializationCodeCheckBox = uicheckbox(app.NelderMeadSetupPanel);
            app.UseCustomInitializationCodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCustomInitializationCodeCheckBoxValueChanged, true);
            app.UseCustomInitializationCodeCheckBox.Text = 'Use custom options object';
            app.InitialKpEditFieldLabel = uilabel(app.NelderMeadSetupPanel);
            app.InitialKpEditFieldLabel.Text = 'Initial Kp:';
            app.InitialKpEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.InitialKiEditFieldLabel = uilabel(app.NelderMeadSetupPanel);
            app.InitialKiEditFieldLabel.Text = 'Initial Ki:';
            app.InitialKiEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.InitialKdEditFieldLabel = uilabel(app.NelderMeadSetupPanel);
            app.InitialKdEditFieldLabel.Text = 'Initial Kd:';
            app.InitialKdEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.CustomOptionsObjectEditField = uieditfield(app.NelderMeadSetupPanel, 'text');
            app.CustomOptionsObjectEditField.Enable = 'off';
            app.CustomoptionsObjectLabel1 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel1.Enable = 'off';
            app.CustomoptionsObjectLabel1.Text = 'Custom options object:';
            app.CustomoptionsObjectLabel2 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel2.Enable = 'off';
            app.CustomoptionsObjectLabel2.Text = 'Provide custom ''options'' object which should be used to set optimization options of Nelder-Mead algorithm';
            app.CustomoptionsObjectLabel3 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel3.Enable = 'off';
            app.CustomoptionsObjectLabel3.Text = ' return such object, or use ''optimset'' function to generate one, e.g.:';
            app.CustomoptionsObjectLabel4 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel4.Enable = 'off';
            app.CustomoptionsObjectLabel4.Text = 'optimset(''MaxFunEvals'', 1000).';
            app.CustomoptionsObjectLabel5 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel5.Enable = 'off';
            app.CustomoptionsObjectLabel5.Text = 'For more details check regarding ''options'' object check ''fminsearch'' function MathWorks support page.';
            app.CustomoptionsObjectLabel6 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel6.Enable = 'off';
            app.CustomoptionsObjectLabel6.Text = 'implemented by Matlab''s fminsearch function. You can provide your own custom function invocation to ';
            app.MaxfunctionevaluationsEditField_2Label = uilabel(app.NelderMeadSetupPanel);
            app.MaxfunctionevaluationsEditField_2Label.Text = 'Max function evaluations*:';
            app.MaxfunctionevaluationsEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxnumberofiterationsEditField_2Label = uilabel(app.NelderMeadSetupPanel);
            app.MaxnumberofiterationsEditField_2Label.Text = 'Max. number of iterations*:';
            app.MaxnumberofiterationsEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.TolFunEditField_2Label = uilabel(app.NelderMeadSetupPanel);
            app.TolFunEditField_2Label.Text = 'TolFun*:';
            app.TolFunEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.TolXEditField_2Label = uilabel(app.NelderMeadSetupPanel);
            app.TolXEditField_2Label.Text = 'TolX*:';
            app.TolXEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.LeaveEmptyLabel = uilabel(app.NelderMeadSetupPanel);
            app.LeaveEmptyLabel.Text = '*Set to 0 to use the default property value';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.MinimizedObjectiveFunctionInfoButton = uibutton(app.UIFigure, 'push');
            app.MinimizedObjectiveFunctionInfoButton.ButtonPushedFcn = createCallbackFcn(app, @MinimizedObjectiveFunctionInfoButtonPushed, true);
            app.MinimizedObjectiveFunctionInfoButton.Icon = 'information.png';
            app.MinimizedObjectiveFunctionInfoButton.Text = '';
            app.StopParametersSearchButton = uibutton(app.UIFigure, 'push');
            app.StopParametersSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StopParametersSearchButtonPushed, true);
            app.StopParametersSearchButton.Enable = 'off';
            app.StopParametersSearchButton.Text = 'Stop parameters search';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nelderMeadParametersSearchWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end