classdef objectiveFunctionWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TitleLabel                   matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        SelectedobjectivefunctiontypeLabel  matlab.ui.control.Label
        ObjectivefunctionvalueLabel  matlab.ui.control.Label
        ObjectiveFunctionTypeLabel   matlab.ui.control.Label
        ObjectiveFunctionValueLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject)
            % Initialize model
            modelName = fvopidToolbox.app.utils.initializeModel(closedLoopSystemSetupObject, controllerSetupObject);
            
            % Set objective function handler
            objectiveFunctionHandler = fvopidToolbox.app.utils.getObjectiveFunctionHandler(objectiveFunctionSetupObject);
            
            % Set optimization starting point
            parametersVector = [controllerParameters.Kp, controllerParameters.Ki, controllerParameters.Kd, controllerParameters.IntegralParametersVector, controllerParameters.DerivativeParametersVector];
            
            % Set a handler to function which evaluates objective funtion value
            numberOfIntegralOrderParameters = length(controllerParameters.IntegralParametersVector);
            simulationTimeString = num2str(closedLoopSystemSetupObject.SimulationTime);
            setpointValue = closedLoopSystemSetupObject.SetpointValue;
            objectiveFunctionValue = fvopidToolbox.app.optimization.getObjectiveFunctionValue(modelName, numberOfIntegralOrderParameters,...
                    simulationTimeString, setpointValue, objectiveFunctionHandler, parametersVector);
            
            % Display objective function value
            switch objectiveFunctionSetupObject.ObjectiveFunctionType
                case fvopidToolbox.app.utils.ObjectiveFunctionSetup.ISE
                    app.ObjectiveFunctionTypeLabel.Text = 'ISE';
                case fvopidToolbox.app.utils.ObjectiveFunctionSetup.IAE
                    app.ObjectiveFunctionTypeLabel.Text = 'IAE';   
                case fvopidToolbox.app.utils.ObjectiveFunctionSetup.ITAE
                    app.ObjectiveFunctionTypeLabel.Text = 'ITAE';      
                case fvopidToolbox.app.utils.ObjectiveFunctionSetup.ITSE
                    app.ObjectiveFunctionTypeLabel.Text = 'ITSE';           
                case fvopidToolbox.app.utils.ObjectiveFunctionSetup.SSE
                    app.ObjectiveFunctionTypeLabel.Text = 'SSE';
                case fvopidToolbox.app.utils.ObjectiveFunctionSetup.SSTE
                    app.ObjectiveFunctionTypeLabel.Text = 'SSTE';
                case fvopidToolbox.app.utils.ObjectiveFunctionSetup.SST2E
                    app.ObjectiveFunctionTypeLabel.Text = 'SST2E';
                otherwise
                    app.ObjectiveFunctionTypeLabel.Text = 'custom objective function';
            end
            app.ObjectiveFunctionValueLabel.Text = num2str(objectiveFunctionValue);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Objective function value';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SelectedobjectivefunctiontypeLabel = uilabel(app.UIFigure);
            app.SelectedobjectivefunctiontypeLabel.Text = 'Selected objective function type:';
            app.ObjectivefunctionvalueLabel = uilabel(app.UIFigure);
            app.ObjectivefunctionvalueLabel.Text = 'Objective function value:';
            app.ObjectiveFunctionTypeLabel = uilabel(app.UIFigure);
            app.ObjectiveFunctionTypeLabel.Text = '-';
            app.ObjectiveFunctionValueLabel = uilabel(app.UIFigure);
            app.ObjectiveFunctionValueLabel.Text = '-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = objectiveFunctionWindow(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end