classdef optimizationResultsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TitleLabel                      matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        MainAxesWindow                  matlab.ui.control.UIAxes
        DerivativeParametersTextArea    matlab.ui.control.TextArea
        IntegralParametersTextArea      matlab.ui.control.TextArea
        IntegralorderparametersLabel    matlab.ui.control.Label
        DerivativeorderparametersLabel  matlab.ui.control.Label
        KpLabel                         matlab.ui.control.Label
        KiLabel                         matlab.ui.control.Label
        KdLabel                         matlab.ui.control.Label
        KpTextArea                      matlab.ui.control.TextArea
        KiTextArea                      matlab.ui.control.TextArea
        KdTextArea                      matlab.ui.control.TextArea
        ShowAlgorithmConvergenceButton  matlab.ui.control.Button
        AlgorithmExecutionDetailsLabel  matlab.ui.control.Label
        AlgorithmExecutionDetailsTextArea  matlab.ui.control.TextArea
        ShowIntegralOrderButton         matlab.ui.control.Button
        ShowDerivativeOrderButton       matlab.ui.control.Button
        ShowControllerOutputButton      matlab.ui.control.Button
        ShowControlErrorButton          matlab.ui.control.Button
        OptimizationProcessStoppedDuringExecutionLabel  matlab.ui.control.Label
        NegativevalueofintegralorderparametermeansthatLabel  matlab.ui.control.Label
        itworksasthederivativetermwhilethenegativevalueLabel  matlab.ui.control.Label
        ofderivativetermmeansthatitplaysaroleofintegralLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        ConvergenceMatrix
        
        ProcessTime
        ProcessOutput
        IntegralTime
        IntegralOrderOutput
        DerivativeTime
        DerivativeOrderOutput
        ErrorTime
        ErrorOutput
        ControllerOutputTime
        ControllerOutput
        ControllerParameters
        AlgorithmExecutionDetails
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, title, closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, optimizationProcessStoppedDuringExecutionFlag, algorithmExecutionDetails, convergenceMatrix)
            app.TitleLabel.Text = title;
            app.ControllerParameters = controllerParameters;
            app.ConvergenceMatrix = convergenceMatrix;
            app.AlgorithmExecutionDetails = algorithmExecutionDetails;
            
            % Set the text which informs that the optimization process was stopped during execution
            if ( ~optimizationProcessStoppedDuringExecutionFlag )
                app.OptimizationProcessStoppedDuringExecutionLabel.Visible = 'Off';
            end    
            
            % Run the simulation process
            [processTime, processOutput, integralTime, integralOrderOutput, derivativeTime, derivativeOrderOutput, errorTime, errorOutput, controllerOutputTime, controllerOutput] = ...
                    fvopidToolbox.app.utils.getPlantSystemResponse(closedLoopSystemSetupObject, controllerSetupObject, controllerParameters);
            
            app.ProcessTime = processTime;
            app.ProcessOutput = processOutput;
            app.IntegralTime = integralTime;
            app.IntegralOrderOutput = integralOrderOutput;
            app.DerivativeTime = derivativeTime;
            app.DerivativeOrderOutput = derivativeOrderOutput;
            app.ErrorTime = errorTime;
            app.ErrorOutput = errorOutput;
            app.ControllerOutputTime = controllerOutputTime;
            app.ControllerOutput = controllerOutput;
            
            % Set the algorithm execution details
            app.AlgorithmExecutionDetailsTextArea.Value = algorithmExecutionDetails;
            
            % Plot the system response
            plot(app.MainAxesWindow, app.ProcessTime, app.ProcessOutput, 'LineWidth', 2);
            
            % Show parameters values
            app.KpTextArea.Value = num2str(controllerParameters.Kp);
            app.KiTextArea.Value = num2str(controllerParameters.Ki);
            app.KdTextArea.Value = num2str(controllerParameters.Kd);
            app.IntegralParametersTextArea.Value = string(controllerParameters.IntegralParametersVector);
            app.DerivativeParametersTextArea.Value = string(controllerParameters.DerivativeParametersVector);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.MainAppButton.Enable = 'On';
            catch buttonException
                % We should catch all the exceptions related to button enabling action
                % just not to have the situation when window cannot be closed
            end
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.MainAppButton.Enable = 'On';
            catch buttonException
                % We should catch all the exceptions related to button enabling action
                % just not to have the situation when window cannot be closed
            end
            delete(app);
        end

        % Button pushed function: ShowIntegralOrderButton
        function ShowIntegralOrderButtonPushed(app, event)
            app.ShowIntegralOrderButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowIntegralOrderButton, app.IntegralTime, app.IntegralOrderOutput,...
                'Time [s]', 'Integral order value', 'Integral order', 'Integral order');
        end

        % Button pushed function: ShowDerivativeOrderButton
        function ShowDerivativeOrderButtonPushed(app, event)
            app.ShowDerivativeOrderButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowDerivativeOrderButton, app.DerivativeTime, app.DerivativeOrderOutput,...
                'Time [s]', 'Derivative order value', 'Derivative order', 'Derivative order');
        end

        % Button pushed function: ShowControllerOutputButton
        function ShowControllerOutputButtonPushed(app, event)
            app.ShowControllerOutputButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowControllerOutputButton, app.ControllerOutputTime, app.ControllerOutput,...
                'Time [s]', 'Controller output signal value', 'Controller output', 'Controller output');
        end

        % Button pushed function: ShowControlErrorButton
        function ShowControlErrorButtonPushed(app, event)
            app.ShowControlErrorButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowControlErrorButton, app.ErrorTime, app.ErrorOutput,...
                'Time [s]', 'Control error value', 'Control error', 'Control error');
        end

        % Button pushed function: ShowAlgorithmConvergenceButton
        function ShowAlgorithmConvergenceButtonPushed(app, event)
            app.ShowAlgorithmConvergenceButton.Enable = 'Off';
            
            fvopidToolbox.app.axesWindow(app.ShowAlgorithmConvergenceButton, app.ConvergenceMatrix(1, :), app.ConvergenceMatrix(2, :),...
                'Iteration', 'Objective function value', 'Algorithm convergence', 'Algorithm convergence');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile('optimizationResult.txt', 'Save optimization result');
            filename = fullfile(path, file);
            if file ~= 0
                fid = fopen(filename,'w'); 
                fprintf(fid,'Kp: %s\n', num2str(app.ControllerParameters.Kp));
                fprintf(fid,'Ki: %s\n', num2str(app.ControllerParameters.Ki));
                fprintf(fid,'Kd: %s\n', num2str(app.ControllerParameters.Kd));
                fprintf(fid,'Integral parameters: %s\n', regexprep(num2str(app.ControllerParameters.IntegralParametersVector),'\s+',',')); 
                fprintf(fid,'Derivative parameters: %s\n', regexprep(num2str(app.ControllerParameters.DerivativeParametersVector),'\s+',',')); 
                fprintf(fid,'\n%s\n', app.AlgorithmExecutionDetails);
                fclose(fid); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MainAxesWindow = uiaxes(app.UIFigure);
            title(app.MainAxesWindow, 'Optimized controller response')
            xlabel(app.MainAxesWindow, 'x label')
            ylabel(app.MainAxesWindow, 'y label')
            app.DerivativeParametersTextArea = uitextarea(app.UIFigure);
            app.DerivativeParametersTextArea.Editable = 'off';
            app.IntegralParametersTextArea = uitextarea(app.UIFigure);
            app.IntegralParametersTextArea.Editable = 'off';
            app.IntegralorderparametersLabel = uilabel(app.UIFigure);
            app.IntegralorderparametersLabel.Text = 'Integral order parameters:';
            app.DerivativeorderparametersLabel = uilabel(app.UIFigure);
            app.DerivativeorderparametersLabel.Text = 'Derivative order parameters:';
            app.KpLabel = uilabel(app.UIFigure);
            app.KpLabel.Text = 'Kp:';
            app.KiLabel = uilabel(app.UIFigure);
            app.KiLabel.Text = 'Ki:';
            app.KdLabel = uilabel(app.UIFigure);
            app.KdLabel.Text = 'Kd:';
            app.KpTextArea = uitextarea(app.UIFigure);
            app.KpTextArea.Editable = 'off';
            app.KiTextArea = uitextarea(app.UIFigure);
            app.KiTextArea.Editable = 'off';
            app.KdTextArea = uitextarea(app.UIFigure);
            app.KdTextArea.Editable = 'off';
            app.ShowAlgorithmConvergenceButton = uibutton(app.UIFigure, 'push');
            app.ShowAlgorithmConvergenceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAlgorithmConvergenceButtonPushed, true);
            app.ShowAlgorithmConvergenceButton.Text = 'Show algorithm convergence';
            app.AlgorithmExecutionDetailsLabel = uilabel(app.UIFigure);
            app.AlgorithmExecutionDetailsLabel.Text = 'Algorithm execution details';
            app.AlgorithmExecutionDetailsTextArea = uitextarea(app.UIFigure);
            app.AlgorithmExecutionDetailsTextArea.Editable = 'off';
            app.ShowIntegralOrderButton = uibutton(app.UIFigure, 'push');
            app.ShowIntegralOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowIntegralOrderButtonPushed, true);
            app.ShowIntegralOrderButton.Text = 'Show integral order';
            app.ShowDerivativeOrderButton = uibutton(app.UIFigure, 'push');
            app.ShowDerivativeOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDerivativeOrderButtonPushed, true);
            app.ShowDerivativeOrderButton.Text = 'Show derivative order';
            app.ShowControllerOutputButton = uibutton(app.UIFigure, 'push');
            app.ShowControllerOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowControllerOutputButtonPushed, true);
            app.ShowControllerOutputButton.Text = 'Show controller output';
            app.ShowControlErrorButton = uibutton(app.UIFigure, 'push');
            app.ShowControlErrorButton.ButtonPushedFcn = createCallbackFcn(app, @ShowControlErrorButtonPushed, true);
            app.ShowControlErrorButton.Text = 'Show control error';
            app.OptimizationProcessStoppedDuringExecutionLabel = uilabel(app.UIFigure);
            app.OptimizationProcessStoppedDuringExecutionLabel.Text = 'Optimization process stopped during execution';
            app.NegativevalueofintegralorderparametermeansthatLabel = uilabel(app.UIFigure);
            app.NegativevalueofintegralorderparametermeansthatLabel.Text = '* Negative value of integral order parameter means that';
            app.itworksasthederivativetermwhilethenegativevalueLabel = uilabel(app.UIFigure);
            app.itworksasthederivativetermwhilethenegativevalueLabel.Text = 'it works as the derivative term, while the negative value';
            app.ofderivativetermmeansthatitplaysaroleofintegralLabel = uilabel(app.UIFigure);
            app.ofderivativetermmeansthatitplaysaroleofintegralLabel.Text = 'of derivative term means that it plays a role of integral.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimizationResultsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end