classdef particleSwarmParametersSearchWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ParticleswarmparameterssearchLabel  matlab.ui.control.Label
        StartParametersSearchButton    matlab.ui.control.Button
        NelderMeadSetupPanel           matlab.ui.container.Panel
        UseCustomInitializationCodeCheckBox  matlab.ui.control.CheckBox
        SwarmsizeLabel                 matlab.ui.control.Label
        SwarmsizeEditField             matlab.ui.control.NumericEditField
        MaxiterationsLabel             matlab.ui.control.Label
        MaxiterationsEditField         matlab.ui.control.NumericEditField
        MaxstalliterationsLabel        matlab.ui.control.Label
        MaxstalliterationsEditField    matlab.ui.control.NumericEditField
        MaxstalltimeLabel              matlab.ui.control.Label
        MaxstalltimeEditField          matlab.ui.control.NumericEditField
        ObjectivelimitLabel            matlab.ui.control.Label
        ObjectivelimitEditField        matlab.ui.control.NumericEditField
        MaxtimeLabel                   matlab.ui.control.Label
        MaxtimeEditField               matlab.ui.control.NumericEditField
        InertiarangeLabel              matlab.ui.control.Label
        InertiarangeEditField          matlab.ui.control.NumericEditField
        SelfadjustmentweightLabel      matlab.ui.control.Label
        SelfadjustmentweightEditField  matlab.ui.control.NumericEditField
        SocialadjustmentweightLabel    matlab.ui.control.Label
        SocialadjustmentweightEditField  matlab.ui.control.NumericEditField
        InitialswarmspanLabel          matlab.ui.control.Label
        InitialswarmspanEditField      matlab.ui.control.NumericEditField
        MinneighborsfractionLabel      matlab.ui.control.Label
        MinneighborsfractionEditField  matlab.ui.control.NumericEditField
        LeaveEmptyLabel                matlab.ui.control.Label
        CustomoptionsObjectLabel2      matlab.ui.control.Label
        CustomoptionsObjectLabel3      matlab.ui.control.Label
        CustomoptionsObjectLabel5      matlab.ui.control.Label
        CustomoptionsObjectLabel6      matlab.ui.control.Label
        CustomoptionsObjectLabel4      matlab.ui.control.Label
        CustomInitializationCodeEditField  matlab.ui.control.EditField
        CloseButton                    matlab.ui.control.Button
        StopParametersSearchButton     matlab.ui.control.Button
        MinimizedObjectiveFunctionInfoButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ClosedLoopSystemSetupObject
        ControllerSetupObject
        ControllerParameters
        ObjectiveFunctionSetupObject
        Kp
        Ki
        Kd
        IntegralParametersVector
        DerivativeParametersVector
        StopParameterSearchingFlag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject)
            app.ClosedLoopSystemSetupObject = closedLoopSystemSetupObject;
            app.ControllerSetupObject = controllerSetupObject;
            app.ControllerParameters = controllerParameters;
            app.ObjectiveFunctionSetupObject = objectiveFunctionSetupObject;
            
            % Initialize properties
            app.Kp = app.ControllerParameters.Kp;
            app.Ki = app.ControllerParameters.Ki;
            app.Kd = app.ControllerParameters.Kd;
            app.IntegralParametersVector = app.ControllerParameters.IntegralParametersVector;
            app.DerivativeParametersVector = app.ControllerParameters.DerivativeParametersVector;

            % Set the flag which indicates if parameters searching should be stopped
            app.StopParameterSearchingFlag = false;
        end

        % Value changed function: 
        % UseCustomInitializationCodeCheckBox
        function UseCustomInitializationCodeCheckBoxValueChanged(app, event)
            value = app.UseCustomInitializationCodeCheckBox.Value;
            
            if ( value )
                app.SwarmsizeEditField.Enable = 'Off';
                app.MaxiterationsEditField.Enable = 'Off';
                app.MaxstalliterationsEditField.Enable = 'Off';
                app.MaxstalltimeEditField.Enable = 'Off';
                app.ObjectivelimitEditField.Enable = 'Off';
                app.MaxtimeEditField.Enable = 'Off';
                app.InertiarangeEditField.Enable = 'Off';
                app.SelfadjustmentweightEditField.Enable = 'Off';
                app.SocialadjustmentweightEditField.Enable = 'Off';
                app.InitialswarmspanEditField.Enable = 'Off';
                app.MinneighborsfractionEditField.Enable = 'Off';
                
                app.CustomInitializationCodeEditField.Enable = 'On';
            else
                app.SwarmsizeEditField.Enable = 'On';
                app.MaxiterationsEditField.Enable = 'On';
                app.MaxstalliterationsEditField.Enable = 'On';
                app.MaxstalltimeEditField.Enable = 'On';
                app.ObjectivelimitEditField.Enable = 'On';
                app.MaxtimeEditField.Enable = 'On';
                app.InertiarangeEditField.Enable = 'On';
                app.SelfadjustmentweightEditField.Enable = 'On';
                app.SocialadjustmentweightEditField.Enable = 'On';
                app.InitialswarmspanEditField.Enable = 'On';
                app.MinneighborsfractionEditField.Enable = 'On';
                
                app.CustomInitializationCodeEditField.Enable = 'Off';
            end
        end

        % Button pushed function: StartParametersSearchButton
        function StartParametersSearchButtonPushed(app, event)
            
            % Initialize model
            modelName = fvopidToolbox.app.utils.initializeModel(app.ClosedLoopSystemSetupObject, app.ControllerSetupObject);
            
            % Set objective function handler
            objectiveFunctionHandler = fvopidToolbox.app.utils.getObjectiveFunctionHandler(app.ObjectiveFunctionSetupObject);
            
            % Set a handler to function which evaluates objective funtion value
            numberOfIntegralOrderParameters = length(app.IntegralParametersVector);
            numberOfDerivativeOrderParameters = length(app.IntegralParametersVector);
            simulationTimeString = num2str(app.ClosedLoopSystemSetupObject.SimulationTime);
            setpointValue = app.ClosedLoopSystemSetupObject.SetpointValue;
            objectiveFunctionValueHandler = @(parametersVector)fvopidToolbox.app.optimization.getObjectiveFunctionValue(modelName, numberOfIntegralOrderParameters,...
                simulationTimeString, setpointValue, objectiveFunctionHandler, parametersVector);
            
            % Create options object for optimization
            customInitializationCode = app.UseCustomInitializationCodeCheckBox.Value;
            if ( customInitializationCode )
                options = eval(app.CustomInitializationCodeEditField.Value);
            else
                options = optimoptions('particleswarm');
                
                swarmSize = app.SwarmsizeEditField.Value;
                maxIteration = app.MaxiterationsEditField.Value;
                maxStallIterations = app.MaxstalliterationsEditField.Value;
                maxStallTime = app.MaxstalltimeEditField.Value;
                objectiveLimit = app.ObjectivelimitEditField.Value;
                maxTime = app.MaxtimeEditField.Value;
                inertiaRange = app.InertiarangeEditField.Value;
                selfAdjustmentWeight = app.SelfadjustmentweightEditField.Value;
                socialAdjustmentWeight = app.SocialadjustmentweightEditField.Value;
                initialSwarmSpan = app.InitialswarmspanEditField.Value;
                minNeighborsFratcion = app.MinneighborsfractionEditField.Value;
                
                if swarmSize ~= 0
                    options.SwarmSize = swarmSize;
                end
                
                if maxIteration ~= 0
                    options.MaxIterations = maxIteration;
                end
                
                if maxStallIterations ~= 0
                    options.MaxStallIterations = maxStallIterations;
                end
                                
                if maxStallTime ~= 0
                    options.MaxStallTime = maxStallTime;
                end
                                                
                if objectiveLimit ~= 0
                    options.ObjectiveLimit = objectiveLimit;
                end
                
                if maxTime ~= 0
                    options.MaxTime = maxTime;
                end
                                                                
                if inertiaRange ~= 0
                    options.InertiaRange = inertiaRange;
                end
                                                                                
                if selfAdjustmentWeight ~= 0
                    options.SelfAdjustmentWeight = selfAdjustmentWeight;
                end
                                                                                                
                if socialAdjustmentWeight ~= 0
                    options.SocialAdjustmentWeight = socialAdjustmentWeight;
                end
                                                                                                                
                if initialSwarmSpan ~= 0
                    options.InitialSwarmSpan = initialSwarmSpan;
                end
                                                                                                                                
                 if minNeighborsFratcion ~= 0
                    options.MinNeighborsFraction = minNeighborsFratcion;
                 end                                                                                                                           
            end
            
            app.StartParametersSearchButton.Enable = 'Off';
            app.StopParametersSearchButton.Enable = 'On';
            
            % Run searching process
            optimizationConvergenceIterationVector = [];
            optimizationConvergenceObjectiveFunctionVector = [];
            progressFigure = uifigure;
            progressDialog = uiprogressdlg(progressFigure, 'Title', 'Please wait', 'Message', "Running optimization oprocess..."); 
            
            % Set the function which monitors progress of the optimization process
            % The function is embedded to use external variables to communicate with the application
            function stop = monitorProgressFunction(optimValues, state)
                if app.StopParameterSearchingFlag
                    stop = true;
                else 
                    stop = false;
                end
                
                progressDialog.Message = ["Running optimization oprocess...",... 
                    strcat("Number of algorithm iteration: ", num2str(optimValues.iteration)),...
                    strcat("Best (lowest) objective function value found: ", num2str(optimValues.bestfval))];
                
                optimizationConvergenceIterationVector = [optimizationConvergenceIterationVector optimValues.iteration];
                optimizationConvergenceObjectiveFunctionVector = [optimizationConvergenceObjectiveFunctionVector optimValues.bestfval];
            end
            options.OutputFcn = @monitorProgressFunction;
            
            try 
                nvars = 3 + numberOfIntegralOrderParameters + numberOfDerivativeOrderParameters;
                set_param(modelName,'FastRestart','on')
                [resultParameters, FVAL, EXITFLAG, OUTPUT] = particleswarm(objectiveFunctionValueHandler, nvars, [], [], options);
                set_param(modelName,'FastRestart','off')
    
                parametersSearchingStopped = app.StopParameterSearchingFlag;
                app.StopParameterSearchingFlag = false;
                
                % Create object which stores controller parameters
                optimizedControllerParametersObject = fvopidToolbox.app.utils.ControllerParameters;
                optimizedControllerParametersObject.Kp = resultParameters(1);
                optimizedControllerParametersObject.Ki = resultParameters(2);
                optimizedControllerParametersObject.Kd = resultParameters(3);
                optimizedControllerParametersObject.IntegralParametersVector = resultParameters(4:3 + numberOfIntegralOrderParameters);
                optimizedControllerParametersObject.DerivativeParametersVector = resultParameters(4 + numberOfIntegralOrderParameters:end);
                
                % Open window which shows the optimization results
                convergenceMatrix = [optimizationConvergenceIterationVector; optimizationConvergenceObjectiveFunctionVector];
                algorithmExecutionDetails = [strcat("Objective function value:    ", num2str(FVAL)),...
                    " ",...
                    strcat("Exit flag:    ", num2str(EXITFLAG)),...
                    strcat("Number of iterations:   ", num2str(OUTPUT.iterations)),...
                    strcat("Number of function evaluations:   ", num2str(OUTPUT.funccount)),...
                    strcat("Algorithm:   ", "Particle Swarm"),...
                    strcat("Exit message:   ", num2str(OUTPUT.message))];
                fvopidToolbox.app.optimizationResultsWindow("Particle Swarm parameters searching results", app.ClosedLoopSystemSetupObject, app.ControllerSetupObject, optimizedControllerParametersObject, parametersSearchingStopped, algorithmExecutionDetails, convergenceMatrix);
                
                % Turn off progress dialog  
                close(progressFigure);
                
                % Close system
                close_system(modelName, false);
            catch Ex
                set_param(modelName,'FastRestart','off')
                
                % Turn off progress dialog
                close(progressFigure);
                
                % Close system
                close_system(modelName, false);
                
                % Set the buttons
                app.StartParametersSearchButton.Enable = 'On';
                app.StopParametersSearchButton.Enable = 'Off';
                
                rethrow(Ex);
            end
            
            % Set the buttons
            app.StartParametersSearchButton.Enable = 'On';
            app.StopParametersSearchButton.Enable = 'Off';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StopParametersSearchButton
        function StopParametersSearchButtonPushed(app, event)
            app.StopParameterSearchingFlag = true;
        end

        % Button pushed function: 
        % MinimizedObjectiveFunctionInfoButton
        function MinimizedObjectiveFunctionInfoButtonPushed(app, event)
            app.MinimizedObjectiveFunctionInfoButton.Enable = 'off';
            fvopidToolbox.app.infoParticleSwarmOptimizationWindow(app, app.MinimizedObjectiveFunctionInfoButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ParticleswarmparameterssearchLabel = uilabel(app.UIFigure);
            app.ParticleswarmparameterssearchLabel.Text = 'Particle swarm parameters search';
            app.StartParametersSearchButton = uibutton(app.UIFigure, 'push');
            app.StartParametersSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StartParametersSearchButtonPushed, true);
            app.StartParametersSearchButton.Text = 'Start parameters search';
            app.NelderMeadSetupPanel = uipanel(app.UIFigure);
            app.NelderMeadSetupPanel.Title = 'Particle swarm setup';
            app.UseCustomInitializationCodeCheckBox = uicheckbox(app.NelderMeadSetupPanel);
            app.UseCustomInitializationCodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCustomInitializationCodeCheckBoxValueChanged, true);
            app.UseCustomInitializationCodeCheckBox.Text = 'Use custom initialization code';
            app.SwarmsizeLabel = uilabel(app.NelderMeadSetupPanel);
            app.SwarmsizeLabel.Text = 'Swarm size*:';
            app.SwarmsizeEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.SwarmsizeEditField.LowerLimitInclusive = 'off';
            app.SwarmsizeEditField.UpperLimitInclusive = 'off';
            app.SwarmsizeEditField.ValueDisplayFormat = '%.0f';
            app.MaxiterationsLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxiterationsLabel.Text = 'Max iterations*:';
            app.MaxiterationsEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxiterationsEditField.LowerLimitInclusive = 'off';
            app.MaxiterationsEditField.UpperLimitInclusive = 'off';
            app.MaxiterationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxstalliterationsLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxstalliterationsLabel.Text = 'Max stall iterations*:';
            app.MaxstalliterationsEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxstalliterationsEditField.LowerLimitInclusive = 'off';
            app.MaxstalliterationsEditField.UpperLimitInclusive = 'off';
            app.MaxstalliterationsEditField.ValueDisplayFormat = '%.0f';
            app.MaxstalltimeLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxstalltimeLabel.Text = 'Max stall time*:';
            app.MaxstalltimeEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxstalltimeEditField.LowerLimitInclusive = 'off';
            app.MaxstalltimeEditField.UpperLimitInclusive = 'off';
            app.MaxstalltimeEditField.ValueDisplayFormat = '%.0f';
            app.ObjectivelimitLabel = uilabel(app.NelderMeadSetupPanel);
            app.ObjectivelimitLabel.Text = 'Objective limit*:';
            app.ObjectivelimitEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.ObjectivelimitEditField.LowerLimitInclusive = 'off';
            app.ObjectivelimitEditField.UpperLimitInclusive = 'off';
            app.ObjectivelimitEditField.ValueDisplayFormat = '%.0f';
            app.MaxtimeLabel = uilabel(app.NelderMeadSetupPanel);
            app.MaxtimeLabel.Text = 'Max. time*:';
            app.MaxtimeEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MaxtimeEditField.LowerLimitInclusive = 'off';
            app.MaxtimeEditField.UpperLimitInclusive = 'off';
            app.MaxtimeEditField.ValueDisplayFormat = '%.0f';
            app.InertiarangeLabel = uilabel(app.NelderMeadSetupPanel);
            app.InertiarangeLabel.Text = 'Inertia range*:';
            app.InertiarangeEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.InertiarangeEditField.LowerLimitInclusive = 'off';
            app.InertiarangeEditField.UpperLimitInclusive = 'off';
            app.InertiarangeEditField.ValueDisplayFormat = '%.0f';
            app.SelfadjustmentweightLabel = uilabel(app.NelderMeadSetupPanel);
            app.SelfadjustmentweightLabel.Text = 'Self adjustment weight*:';
            app.SelfadjustmentweightEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.SelfadjustmentweightEditField.LowerLimitInclusive = 'off';
            app.SelfadjustmentweightEditField.UpperLimitInclusive = 'off';
            app.SelfadjustmentweightEditField.ValueDisplayFormat = '%.0f';
            app.SocialadjustmentweightLabel = uilabel(app.NelderMeadSetupPanel);
            app.SocialadjustmentweightLabel.Text = 'Social adjustment weight*:';
            app.SocialadjustmentweightEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.SocialadjustmentweightEditField.LowerLimitInclusive = 'off';
            app.SocialadjustmentweightEditField.UpperLimitInclusive = 'off';
            app.SocialadjustmentweightEditField.ValueDisplayFormat = '%.0f';
            app.InitialswarmspanLabel = uilabel(app.NelderMeadSetupPanel);
            app.InitialswarmspanLabel.Text = 'Initial swarm span*:';
            app.InitialswarmspanEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.InitialswarmspanEditField.LowerLimitInclusive = 'off';
            app.InitialswarmspanEditField.UpperLimitInclusive = 'off';
            app.InitialswarmspanEditField.ValueDisplayFormat = '%.0f';
            app.MinneighborsfractionLabel = uilabel(app.NelderMeadSetupPanel);
            app.MinneighborsfractionLabel.Text = 'Min. neighbors fraction*:';
            app.MinneighborsfractionEditField = uieditfield(app.NelderMeadSetupPanel, 'numeric');
            app.MinneighborsfractionEditField.LowerLimitInclusive = 'off';
            app.MinneighborsfractionEditField.UpperLimitInclusive = 'off';
            app.MinneighborsfractionEditField.ValueDisplayFormat = '%.0f';
            app.LeaveEmptyLabel = uilabel(app.NelderMeadSetupPanel);
            app.LeaveEmptyLabel.Text = '*Set to 0 to use the default property value';
            app.CustomoptionsObjectLabel2 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel2.Enable = 'off';
            app.CustomoptionsObjectLabel2.Text = 'Provide custom ''optimoptions'' object which should be used to set optimization options of Particle Swarm ';
            app.CustomoptionsObjectLabel3 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel3.Enable = 'off';
            app.CustomoptionsObjectLabel3.Text = 'invocation to return such object, or use ''optimset'' function to generate one, e.g.:';
            app.CustomoptionsObjectLabel5 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel5.Enable = 'off';
            app.CustomoptionsObjectLabel5.Text = 'For more details check regarding ''optimoptions'' object check ''particleswarm'' function MathWorks support page.';
            app.CustomoptionsObjectLabel6 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel6.Enable = 'off';
            app.CustomoptionsObjectLabel6.Text = 'algorithm implemented by Matlab''s particleswarm function. You can provide your own custom function ';
            app.CustomoptionsObjectLabel4 = uilabel(app.NelderMeadSetupPanel);
            app.CustomoptionsObjectLabel4.Enable = 'off';
            app.CustomoptionsObjectLabel4.Text = 'optimoptions(''particleswarm'', ''SwarmSize'', 100).';
            app.CustomInitializationCodeEditField = uieditfield(app.NelderMeadSetupPanel, 'text');
            app.CustomInitializationCodeEditField.Enable = 'off';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.StopParametersSearchButton = uibutton(app.UIFigure, 'push');
            app.StopParametersSearchButton.ButtonPushedFcn = createCallbackFcn(app, @StopParametersSearchButtonPushed, true);
            app.StopParametersSearchButton.Enable = 'off';
            app.StopParametersSearchButton.Text = 'Stop parameters search';
            app.MinimizedObjectiveFunctionInfoButton = uibutton(app.UIFigure, 'push');
            app.MinimizedObjectiveFunctionInfoButton.ButtonPushedFcn = createCallbackFcn(app, @MinimizedObjectiveFunctionInfoButtonPushed, true);
            app.MinimizedObjectiveFunctionInfoButton.Icon = 'information.png';
            app.MinimizedObjectiveFunctionInfoButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particleSwarmParametersSearchWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end