classdef removeIntervalValue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ApplyButton               matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        IntervalValuesTextArea    matlab.ui.control.TextArea
        RemoveIntervalValueLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        updateCallingAppFunction
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, updateCallingAppFunction)
            app.updateCallingAppFunction = updateCallingAppFunction;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            intervalValuesString = strtrim(sprintf('%s\n', app.IntervalValuesTextArea.Value{:}));
            
            if ~isempty(intervalValuesString)
                % TODO: we need validation here
                intervalValuesArray = str2num(intervalValuesString);
                
                app.updateCallingAppFunction(intervalValuesArray);
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Provide single Interval Value or a comma separated set of Inteval Values:';
            app.IntervalValuesTextArea = uitextarea(app.UIFigure);
            app.RemoveIntervalValueLabel = uilabel(app.UIFigure);
            app.RemoveIntervalValueLabel.Text = 'Remove Interval Value';
        end
    end

    methods (Access = public)

        % Construct app
        function app = removeIntervalValue(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end