classdef stepResponseAxesWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TitleLabel                  matlab.ui.control.Label
        CloseButton                 matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        MainAxesWindow              matlab.ui.control.UIAxes
        ShowIntegralOrderButton     matlab.ui.control.Button
        ShowDerivativeOrderButton   matlab.ui.control.Button
        ShowControlErrorButton      matlab.ui.control.Button
        ShowControllerOutputButton  matlab.ui.control.Button
    end


    properties (Access = private)
        ProcessTime
        ProcessOutput
        IntegralTime
        IntegralOrderOutput
        DerivativeTime
        DerivativeOrderOutput
        ErrorTime
        ErrorOutput
        ControllerOutputTime
        ControllerOutput
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, closedLoopSystemSetupObject, controllerSetupObject, controllerParameters)
           [processTime, processOutput, integralTime, integralOrderOutput, derivativeTime, derivativeOrderOutput, errorTime, errorOutput, controllerOutputTime, controllerOutput] = ...
                    fvopidToolbox.app.utils.getPlantSystemResponse(closedLoopSystemSetupObject, controllerSetupObject, controllerParameters);
            
            app.ProcessTime = processTime;
            app.ProcessOutput = processOutput;
            app.IntegralTime = integralTime;
            app.IntegralOrderOutput = integralOrderOutput;
            app.DerivativeTime = derivativeTime;
            app.DerivativeOrderOutput = derivativeOrderOutput;
            app.ErrorTime = errorTime;
            app.ErrorOutput = errorOutput;
            app.ControllerOutputTime = controllerOutputTime;
            app.ControllerOutput = controllerOutput;
            
            plot(app.MainAxesWindow, app.ProcessTime, app.ProcessOutput, 'LineWidth', 2);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: ShowIntegralOrderButton
        function ShowIntegralOrderButtonPushed(app, event)
            app.ShowIntegralOrderButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowIntegralOrderButton, app.IntegralTime, app.IntegralOrderOutput,...
                'Time [s]', 'Integral order value', 'Integral order', 'Integral order');
        end

        % Button pushed function: ShowDerivativeOrderButton
        function ShowDerivativeOrderButtonPushed(app, event)
            app.ShowDerivativeOrderButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowDerivativeOrderButton, app.DerivativeTime, app.DerivativeOrderOutput,...
                'Time [s]', 'Derivative order value', 'Derivative order', 'Derivative order');
        end

        % Button pushed function: ShowControlErrorButton
        function ShowControlErrorButtonPushed(app, event)
            app.ShowControlErrorButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowControlErrorButton, app.ErrorTime, app.ErrorOutput,...
                'Time [s]', 'Control error value', 'Control error', 'Control error');
        end

        % Button pushed function: ShowControllerOutputButton
        function ShowControllerOutputButtonPushed(app, event)
            app.ShowControllerOutputButton.Enable = 'Off';
            fvopidToolbox.app.axesWindow(app.ShowControllerOutputButton, app.ControllerOutputTime, app.ControllerOutput,...
                'Time [s]', 'Controller output signal value', 'Controller output', 'Controller output');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            csvMatrix = [app.ProcessTime, app.ProcessOutput, app.IntegralOrderOutput, app.DerivativeOrderOutput, app.ErrorOutput, app.ControllerOutput];
            
            [file, path] = uiputfile('stepResponse.csv', 'Save step response result');
            filename = fullfile(path, file);
            if file ~= 0
                cHeader = {'Time [s]', 'Process Output', 'Integral Order', 'Derivative order', 'Error Output', 'Controller Output'};
                commaHeader = [cHeader;repmat({','},1,numel(cHeader))]; 
                commaHeader = commaHeader(:)';
                textHeader = cell2mat(commaHeader); 

                fid = fopen(filename,'w'); 
                fprintf(fid,'%s\n',textHeader);
                fclose(fid);
                %write data to end of file
                dlmwrite(filename, csvMatrix,'-append'); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'System step response';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MainAxesWindow = uiaxes(app.UIFigure);
            title(app.MainAxesWindow, 'Step response')
            xlabel(app.MainAxesWindow, 'time [s]')
            ylabel(app.MainAxesWindow, 'System output')
            app.ShowIntegralOrderButton = uibutton(app.UIFigure, 'push');
            app.ShowIntegralOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowIntegralOrderButtonPushed, true);
            app.ShowIntegralOrderButton.Text = 'Show integral order';
            app.ShowDerivativeOrderButton = uibutton(app.UIFigure, 'push');
            app.ShowDerivativeOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDerivativeOrderButtonPushed, true);
            app.ShowDerivativeOrderButton.Text = 'Show derivative order';
            app.ShowControlErrorButton = uibutton(app.UIFigure, 'push');
            app.ShowControlErrorButton.ButtonPushedFcn = createCallbackFcn(app, @ShowControlErrorButtonPushed, true);
            app.ShowControlErrorButton.Text = 'Show control error';
            app.ShowControllerOutputButton = uibutton(app.UIFigure, 'push');
            app.ShowControllerOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowControllerOutputButtonPushed, true);
            app.ShowControllerOutputButton.Text = 'Show controller output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stepResponseAxesWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end