classdef transferFunctionInformationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TitleLabel              matlab.ui.control.Label
        TransferFunctionLabel1  matlab.ui.control.Label
        TransferFunctionEquationTextArea  matlab.ui.control.TextArea
        CloseButton             matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp
        MainAppButton
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mainappButton, tfNominator, tfDenominator, samplingTime)
            app.CallingApp = mainapp;
            app.MainAppButton = mainappButton;
            try 
                if ~isfinite(samplingTime) 
                    plantTf = tf(tfNominator, tfDenominator);
                else
                    plantTf = tf(tfNominator, tfDenominator, samplingTime);
                end
                
                plantChar = evalc('plantTf');
                tfPrefix = 'plantTf =';
                if contains(plantChar, tfPrefix)
                    plantChar = extractAfter(plantChar, tfPrefix);
                end
                app.TransferFunctionEquationTextArea.Value = plantChar;
            catch exception
                exceptionText = getReport(exception);
                app.TransferFunctionEquationTextArea.Value = ['Problem occured during transfer function evaluation: ' exceptionText];
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainAppButton.Enable = 'on';
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Transfer function equation';
            app.TransferFunctionLabel1 = uilabel(app.UIFigure);
            app.TransferFunctionLabel1.Text = 'Selected plant is described by the following transfer function:  ';
            app.TransferFunctionEquationTextArea = uitextarea(app.UIFigure);
            app.TransferFunctionEquationTextArea.Editable = 'off';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transferFunctionInformationWindow(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end