classdef variableOrderDifferentialEquationsSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FractionalvariableorderclosedloopsystemssolverLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ClosedloopsystemsetupTab        matlab.ui.container.Tab
        ControllerbasicparametersPanel  matlab.ui.container.Panel
        ControllertypeLabel             matlab.ui.control.Label
        ControllerTypeDropDown          matlab.ui.control.DropDown
        ControllerBasicParametersInfoButton  matlab.ui.control.Button
        ProportionalPEditFieldLabel     matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        IntegralIEditFieldLabel         matlab.ui.control.Label
        KiEditField                     matlab.ui.control.NumericEditField
        DerivativeDEditFieldLabel       matlab.ui.control.Label
        KdEditField                     matlab.ui.control.NumericEditField
        ControllersamplingtimesEditFieldLabel  matlab.ui.control.Label
        ControllersamplingtimesEditField  matlab.ui.control.NumericEditField
        SaturationPanel                 matlab.ui.container.Panel
        LimitoutputCheckBox             matlab.ui.control.CheckBox
        UpperlimitEditFieldLabel        matlab.ui.control.Label
        UpperlimitEditField             matlab.ui.control.NumericEditField
        LowerlimitEditFieldLabel        matlab.ui.control.Label
        LowerlimitEditField             matlab.ui.control.NumericEditField
        SaturationInfoButton            matlab.ui.control.Button
        PlantsimulationsetupPanel       matlab.ui.container.Panel
        ShowTransferFunctionButton      matlab.ui.control.Button
        PlantInfoButton                 matlab.ui.control.Button
        DiscretePlantCheckBox           matlab.ui.control.CheckBox
        DiscreteplantsampletimesEditFieldLabel  matlab.ui.control.Label
        DiscreteplantsampletimesEditField  matlab.ui.control.NumericEditField
        SimulationtimesEditFieldLabel   matlab.ui.control.Label
        SimulationtimesEditField        matlab.ui.control.NumericEditField
        PlanttransferfunctionnumeratorEditFieldLabel  matlab.ui.control.Label
        PlanttransferfunctionnumeratorEditField  matlab.ui.control.EditField
        ClosedloopsystemsetpointvalueEditFieldLabel  matlab.ui.control.Label
        ClosedloopsystemsetpointvalueEditField  matlab.ui.control.NumericEditField
        PlanttransferfunctiondenominatorEditFieldLabel  matlab.ui.control.Label
        PlanttransferfunctiondenominatorEditField  matlab.ui.control.EditField
        GeneratedoutputsamplingtimesLabel  matlab.ui.control.Label
        GeneratedOutputSamplingTimeEditField  matlab.ui.control.NumericEditField
        TransportdelaysEditFieldLabel   matlab.ui.control.Label
        TransportDelayEditField         matlab.ui.control.NumericEditField
        ClosedLoopSystemImageButton     matlab.ui.control.Button
        IntegralorderTab                matlab.ui.container.Tab
        IntegralorderfunctiontypeDropDownLabel  matlab.ui.control.Label
        IntegralorderfunctiontypeDropDown  matlab.ui.control.DropDown
        IntegralUserDefinedFunctionPanel  matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        IntegralOrderFunctionTextArea   matlab.ui.control.TextArea
        Label_4                         matlab.ui.control.Label
        endLabel                        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        returnsvalueassignedtovariableyandhasthefollowingargumentsLabel  matlab.ui.control.Label
        errorcurrentcontrolerrorvalueLabel  matlab.ui.control.Label
        setpointcurrentvalueofthesetpointLabel  matlab.ui.control.Label
        currentTimethesimulationtimeLabel  matlab.ui.control.Label
        parametersVectoruserdefinedparametersLabel  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        searchedbytheoptimizationalgorithmLabel  matlab.ui.control.Label
        ParametersvectorLabel_2         matlab.ui.control.Label
        IntegralUserDefinedFunctionParametersVectorEditField_2  matlab.ui.control.EditField
        IntegralWarningLabel1           matlab.ui.control.Label
        IntegralWarningLabel2           matlab.ui.control.Label
        IntegralFunctionOfErrorPanel    matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        IntegralUseErrorToSetpointRatioCheckBox  matlab.ui.control.CheckBox
        IntegralOrdersTable             matlab.ui.control.Table
        AddOrderButton                  matlab.ui.control.Button
        RemoveOrderButton               matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        Label_13                        matlab.ui.control.Label
        todeterminethevalueoforderLabel  matlab.ui.control.Label
        haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_4  matlab.ui.control.Label
        IntegralConstantOrderPanel      matlab.ui.container.Panel
        ThecontrollerwillhaveaconstantintegralordervalueLabel  matlab.ui.control.Label
        IntegralorderEditFieldLabel     matlab.ui.control.Label
        IntegralOrderEditField          matlab.ui.control.NumericEditField
        haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_5  matlab.ui.control.Label
        DerivativeorderTab              matlab.ui.container.Tab
        DerivativeorderfunctiontypetypeDropDown_2Label  matlab.ui.control.Label
        DerivativeorderfunctiontypeDropDown  matlab.ui.control.DropDown
        DerivativeConstantOrderPanel    matlab.ui.container.Panel
        ThecontrollerwillhaveaconstantintegralordervalueLabel_2  matlab.ui.control.Label
        DerivativeorderEditFieldLabel   matlab.ui.control.Label
        DerivativeorderEditField        matlab.ui.control.NumericEditField
        haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_6  matlab.ui.control.Label
        DerivativeUserDefinedFunctionPanel  matlab.ui.container.Panel
        TextArea_2Label                 matlab.ui.control.Label
        DervativeOrderFunctionTextArea  matlab.ui.control.TextArea
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        hasthefollowingargumentsLabel_2  matlab.ui.control.Label
        errorcurrentcontrolerrorvalueLabel_2  matlab.ui.control.Label
        setpointcurrentvalueofthesetpointLabel_2  matlab.ui.control.Label
        currentTimethesimulationtimeLabel_2  matlab.ui.control.Label
        parametersVectoruserdefinedparametersLabel_2  matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        searchedbytheoptimizationalgorithmLabel_2  matlab.ui.control.Label
        ParametersvectorLabel           matlab.ui.control.Label
        DerivativeUserDefinedFunctionParametersVectorEditField  matlab.ui.control.EditField
        endLabel_2                      matlab.ui.control.Label
        DerivativeWarningLabel1         matlab.ui.control.Label
        DerivativeWarningLabel2         matlab.ui.control.Label
        DerivativeFunctionOfErrorPanel  matlab.ui.container.Panel
        Label_10                        matlab.ui.control.Label
        haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_2  matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        DerivativeUseErrorToSetpointRatioCheckBox  matlab.ui.control.CheckBox
        DerivativeOrdersTable           matlab.ui.control.Table
        DerviativeAddOrderButton        matlab.ui.control.Button
        DerivativeRemoveOrderButton     matlab.ui.control.Button
        DerivativeSelectAllButton       matlab.ui.control.Button
        DerivativeClearAllButton        matlab.ui.control.Button
        haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_3  matlab.ui.control.Label
        OptimizationsetupTab            matlab.ui.container.Tab
        OptimizationmethodDropDownLabel  matlab.ui.control.Label
        OptimizationMethodDropDown      matlab.ui.control.DropDown
        MinimizedobjectivefunctionDropDownLabel  matlab.ui.control.Label
        MinimizedObjectiveFunctionDropDown  matlab.ui.control.DropDown
        CustomObjectiveFunctionPanel    matlab.ui.container.Panel
        CustomObjectiveFunctionLabel1   matlab.ui.control.Label
        CustomObjectiveFunctionLabel2   matlab.ui.control.Label
        CustomObjectiveFunctionLabel3   matlab.ui.control.Label
        CustomObjectiveFunctionLabel4   matlab.ui.control.Label
        CustomObjectiveFunctionLabel5   matlab.ui.control.Label
        CustomObjectiveFunctionEditField  matlab.ui.control.EditField
        CustomObjectiveFunctionLabel6   matlab.ui.control.Label
        CustomObjectiveFunctionLabel7   matlab.ui.control.Label
        CustomObjectiveFunctionLabel8   matlab.ui.control.Label
        CustomObjectiveFunctionLabel9   matlab.ui.control.Label
        CustomObjectiveFunctionLabel10  matlab.ui.control.Label
        CustomObjectiveFunctionLabel11  matlab.ui.control.Label
        MinimizedObjectiveFunctionInfoButton  matlab.ui.control.Button
        OptimizedMethodInfoButton       matlab.ui.control.Button
        CalculateSelectedObjectiveFunctionButton  matlab.ui.control.Button
        ParametersSearchingButton       matlab.ui.control.Button
        InfoTab                         matlab.ui.container.Tab
        Label_12                        matlab.ui.control.Label
        DEC201623BST703686Label         matlab.ui.control.Label
        ContentTextArea                 matlab.ui.control.TextArea
        ShowSystemResponseButton        matlab.ui.control.Button
    end


    properties (Access = private)
        IntegralOrderData
        DerivativeOrderData
    end

    methods (Access = private)
    
        function disableCustomObjectiveFunctionGuiElements(app)
            app.CustomObjectiveFunctionLabel1.Enable = 'Off';
            app.CustomObjectiveFunctionLabel2.Enable = 'Off';
            app.CustomObjectiveFunctionLabel3.Enable = 'Off';
            app.CustomObjectiveFunctionLabel4.Enable = 'Off';
            app.CustomObjectiveFunctionLabel5.Enable = 'Off';
            app.CustomObjectiveFunctionLabel6.Enable = 'Off';
            app.CustomObjectiveFunctionLabel7.Enable = 'Off';
            app.CustomObjectiveFunctionLabel8.Enable = 'Off';
            app.CustomObjectiveFunctionLabel9.Enable = 'Off';
            app.CustomObjectiveFunctionLabel10.Enable = 'Off';
            app.CustomObjectiveFunctionLabel11.Enable = 'Off';
            app.CustomObjectiveFunctionEditField.Enable = 'Off';
        end
        
        function enableCustomObjectiveFunctionGuiElements(app)
            app.CustomObjectiveFunctionLabel1.Enable = 'On';
            app.CustomObjectiveFunctionLabel2.Enable = 'On';
            app.CustomObjectiveFunctionLabel3.Enable = 'On';
            app.CustomObjectiveFunctionLabel4.Enable = 'On';
            app.CustomObjectiveFunctionLabel5.Enable = 'On';
            app.CustomObjectiveFunctionLabel6.Enable = 'On';
            app.CustomObjectiveFunctionLabel7.Enable = 'On';
            app.CustomObjectiveFunctionLabel8.Enable = 'On';
            app.CustomObjectiveFunctionLabel9.Enable = 'On';
            app.CustomObjectiveFunctionLabel10.Enable = 'On';
            app.CustomObjectiveFunctionLabel11.Enable = 'On';
            app.CustomObjectiveFunctionEditField.Enable = 'On';
        end
        
        function enableCustomInitializationSetupGuiElements(app)
            app.SetCustomInitializationCodeButton.Enable = 'On';
            
            app.MaxfunctionevaluationsEditField.Enable = 'Off';
            app.MaxnumberofiterationsEditField.Enable = 'Off';
            app.TolFunEditField.Enable = 'Off';
            app.TolXEditField.Enable = 'Off';
        end
        
        function disableCustomInitializationSetupGuiElements(app)
            app.SetCustomInitializationCodeButton.Enable = 'Off';
            
            app.MaxfunctionevaluationsEditField.Enable = 'On';
            app.MaxnumberofiterationsEditField.Enable = 'On';
            app.TolFunEditField.Enable = 'On';
            app.TolXEditField.Enable = 'On';
        end
        
        function [closedLoopSystemSetupObject, controllerSetupObject, controllerParameters] = createClosedLoopSetupObjects(app)
            % Create object which stores closed loop system setup information ----------------------------------------
            closedLoopSystemSetupObject = fvopidToolbox.app.utils.ClosedLoopSystemSetup;
            closedLoopSystemSetupObject.SetpointValue = app.ClosedloopsystemsetpointvalueEditField.Value;
            closedLoopSystemSetupObject.ControllerSampleTime = app.ControllersamplingtimesEditField.Value;
            closedLoopSystemSetupObject.OutputSampleTime = app.GeneratedOutputSamplingTimeEditField.Value;
            closedLoopSystemSetupObject.BufferSize = -1; 
            limitOutputCheckBoxValue = app.LimitoutputCheckBox.Value;             
            if ( limitOutputCheckBoxValue )
                closedLoopSystemSetupObject.LimitControllerOutput = true;
            else
                closedLoopSystemSetupObject.LimitControllerOutput = false;
            end
            closedLoopSystemSetupObject.ControllerOutputUpperLimit = app.UpperlimitEditField.Value;
            closedLoopSystemSetupObject.ControllerOutputLowerLimit = app.LowerlimitEditField.Value;
            closedLoopSystemSetupObject.PlantTransferFunctionNominator = str2num(app.PlanttransferfunctionnumeratorEditField.Value);
            closedLoopSystemSetupObject.PlantTransferFunctionDenominator = str2num(app.PlanttransferfunctiondenominatorEditField.Value);
            discretePlantCheckBoxValue = app.DiscretePlantCheckBox.Value;
            if (discretePlantCheckBoxValue)
                closedLoopSystemSetupObject.DiscretePlant = true;
            else
                closedLoopSystemSetupObject.DiscretePlant = false;
            end
            closedLoopSystemSetupObject.DiscretePlantSampleTime = app.DiscreteplantsampletimesEditField.Value;
            closedLoopSystemSetupObject.TransportDelay = app.TransportDelayEditField.Value;
            closedLoopSystemSetupObject.SimulationTime = app.SimulationtimesEditField.Value;
            
            % Create object which stores controller parameters -----------------------------------------------------------
            controllerParameters = fvopidToolbox.app.utils.ControllerParameters;
            controllerParameters.Kp = app.KpEditField.Value;
            controllerParameters.Ki = app.KiEditField.Value;
            controllerParameters.Kd = app.KdEditField.Value;
           
            % Create object which stores controller setup information ----------------------------------------------------
            controllerSetupObject = fvopidToolbox.app.utils.ControllerSetup;
            
            controllerTypeValue = app.ControllerTypeDropDown.Value;
            switch controllerTypeValue
                case 'FVOPID Type A'
                    controllerSetupObject.ControllerType = fvopidToolbox.app.utils.ControllerSetup.FVOPID_TYPE_A;
                otherwise
                    controllerSetupObject.ControllerType = fvopidToolbox.app.utils.ControllerSetup.FVOPID_CONV_TYPE;
            end
            
            integralOrderTypeValue = app.IntegralorderfunctiontypeDropDown.Value;
            switch integralOrderTypeValue
                case 'Constant order' 
                    controllerSetupObject.IntegralOrderType = fvopidToolbox.app.utils.ControllerSetup.CONSTANT_ORDER;
                    controllerParameters.IntegralParametersVector = app.IntegralOrderEditField.Value;
                case 'Function of error'
                    controllerSetupObject.IntegralOrderType = fvopidToolbox.app.utils.ControllerSetup.FUNCTION_OF_ERROR;
                    value = app.IntegralUseErrorToSetpointRatioCheckBox.Value;
                    if ( value )
                        controllerSetupObject.IntegralUseErrorSetpointRatio = true;
                    else
                        controllerSetupObject.IntegralUseErrorSetpointRatio = false;
                    end
                    % TODO: refactoring needed - there should be a separated function to retrieve orders data!!!
                    controllerSetupObject.IntegralVectorOfControlErrorValues = app.IntegralOrderData(1:end-1,1)';
                    controllerParameters.IntegralParametersVector = cell2mat(app.IntegralOrdersTable.Data(:,2))';
                otherwise
                    controllerSetupObject.IntegralOrderType = fvopidToolbox.app.utils.ControllerSetup.USER_DEFINED_FUNCTION;
                    controllerSetupObject.IntegralUserDefinedFunction = sprintf('%s\n', app.IntegralOrderFunctionTextArea.Value{:})
                    controllerParameters.IntegralParametersVector = str2num(app.IntegralUserDefinedFunctionParametersVectorEditField_2.Value);
            end
            
            derivativeOrderTypeValue = app.DerivativeorderfunctiontypeDropDown.Value;
            switch derivativeOrderTypeValue
                case 'Constant order' 
                     controllerSetupObject.DerivativeOrderType = fvopidToolbox.app.utils.ControllerSetup.CONSTANT_ORDER;
                     controllerParameters.DerivativeParametersVector = app.DerivativeorderEditField.Value;
                case 'Function of error'
                    controllerSetupObject.DerivativeOrderType = fvopidToolbox.app.utils.ControllerSetup.FUNCTION_OF_ERROR;
                    value = app.DerivativeUseErrorToSetpointRatioCheckBox.Value;
                    if ( value )
                        controllerSetupObject.DerivativeUseErrorSetpointRatio = true;
                        elseW
                        controllerSetupObject.DerivativeUseErrorSetpointRatio = false;
                    end
                    % TODO: refactoring needed - there should be a separated function to retrieve orders data!!!
                    controllerSetupObject.DerivativeVectorOfControlErrorValues = app.DerivativeOrderData(1:end-1,1)';
                    controllerParameters.DerivativeParametersVector = cell2mat(app.DerivativeOrdersTable.Data(:,2))';
                otherwise
                    controllerSetupObject.DerivativeOrderType = fvopidToolbox.app.utils.ControllerSetup.USER_DEFINED_FUNCTION;
                    controllerSetupObject.DerivativeUserDefinedFunction = sprintf('%s\n', app.DervativeOrderFunctionTextArea.Value{:});
                    controllerParameters.DerivativeParametersVector = str2num(app.DerivativeUserDefinedFunctionParametersVectorEditField.Value);
            end
        end    

        function objectiveFunctionSetupObject = createObjectiveFunctionSetupObject(app)
            objectiveFunctionSetupObject = fvopidToolbox.app.utils.ObjectiveFunctionSetup;
            
            minimizedObjectiveFunctionType = app.MinimizedObjectiveFunctionDropDown.Value;
            
            switch minimizedObjectiveFunctionType
                case 'ISE'
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.ISE;
                case 'IAE'
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.IAE;
                case 'ITAE'
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.ITAE;
                case 'ITSE'
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.ITSE;
                case 'SSE'
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.SSE; 
                case 'SSTE'
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.SSTE;
                case 'SST2E'
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.SST2E;
                otherwise
                    objectiveFunctionSetupObject.ObjectiveFunctionType = fvopidToolbox.app.utils.ObjectiveFunctionSetup.USER_DEFINED;
                    objectiveFunctionSetupObject.CustomObjectiveFunctionInvocation = app.CustomObjectiveFunctionEditField.Value;
            end
        end
        
        function tableData = getOrdersTableValues(app, orderData)
            orderDataLength = size(orderData, 1);

            if orderDataLength == 1
               % Remove all 
               tableData = [];
            else 
                tableData = cell(orderDataLength, 3, 1);
                for i = 1:orderDataLength
                    if i == 1
                        tableData(i, 1) = {strcat('error > ', num2str(orderData(i, 1)))};
                    elseif i == orderDataLength
                        tableData(i, 1) = {strcat('error <= ', num2str(orderData(i-1 , 1)))};
                    else
                        tableData(i, 1) = {strcat([num2str(orderData(i, 1)), ' < error <= ', num2str(orderData(i-1, 1))])};
                    end
                    
                    if isnan(orderData(i, 2))
                        tableData(i, 2) = {NaN};
                    else
                        tableData(i, 2) = {orderData(i, 2)};
                    end
                    
                    tableData(i, 3) = {false};
                end
            end
        end
        % TODO: refactoring!!!
        function [validationCorrect, errorMessage] = validateControllerSetup(app)
            incorrectOrdersMessage = 'Function of error cannot have empty or NaN values! Please check the setup of the';
            validationCorrect = true;
            errorMessage = '';
            
            integralOrderTypeValue = app.IntegralorderfunctiontypeDropDown.Value;
            if strcmp(integralOrderTypeValue, 'Function of error')
                if isempty(app.IntegralOrdersTable.Data)
                    validationCorrect = false;
                    errorMessage = [incorrectOrdersMessage, ' integral order function']; 
                else
                    integralOrders = app.IntegralOrdersTable.Data(:,2);
                    if any(isempty(integralOrders{1})) || any(~isnumeric(integralOrders{1})) || any(isnan(integralOrders{1}))
                        validationCorrect = false;
                        errorMessage = [incorrectOrdersMessage, ' integral order function']; 
                    end
                end
            end
            
            derivativeOrderTypeValue = app.DerivativeorderfunctiontypeDropDown.Value;
            if strcmp(derivativeOrderTypeValue, 'Function of error')
                if isempty(app.DerivativeOrdersTable.Data)
                    validationCorrect = false;
                    if isempty(errorMessage)
                       errorMessage = [incorrectOrdersMessage, ' derivative order function']; 
                    else
                       errorMessage = [errorMessage, ' and the derivative order function']; 
                    end
                else
                    derivativeOrders = app.DerivativeOrdersTable.Data(:,2);
                    if any(isempty(derivativeOrders{1})) || any(~isnumeric(derivativeOrders{1})) || any(isnan(derivativeOrders{1}))
                        validationCorrect = false;
                        if isempty(errorMessage)
                           errorMessage = [incorrectOrdersMessage, ' derivative order function']; 
                        else
                           errorMessage = [errorMessage, ' and the derivative order function']; 
                        end
                    end
                end
            end
            
            if ~validationCorrect
                errorMessage = [errorMessage, '.'];
            end
        end 
        
        function [validationCorrect, errorMessage] = validateOptimizationSetup(app)
            validationCorrect = true;
            errorMessage = '';
            if app.SimulationtimesEditField.Value / app.ControllersamplingtimesEditField.Value  > 5000
                validationCorrect = false;
                errorMessage = 'Parameters searching procedure is currently only available for the simulations where the total number of calculated samples do not exeed 5000. In the other words the simulation time divided by the controler sampling time cannot give a number higher than 5000.';
            end
        end
    end

    methods (Access = public)
        % Change this part - the code is repeatedm refactoring needed!!!
        function addIntegralIntervalValues(app, valuesSet)
            app.IntegralOrdersTable.Data
            length(app.IntegralOrdersTable.Data)
            if ~isempty(valuesSet)
                % Load orders data - only if the table is not empty
                if ~isempty(app.IntegralOrdersTable.Data)
                    for i = 1:length(app.IntegralOrdersTable.Data)
                        orderCell = app.IntegralOrdersTable.Data(i, 2);
                        orderCellValue = orderCell{1};

                        if isempty(orderCellValue) || ~isnumeric(orderCellValue)
                            app.IntegralOrderData(i, 2) = 1;
                        else
                            app.IntegralOrderData(i, 2) = orderCellValue;
                        end
                    end
 
                end
               
                % Update intervals array
                for i = 1:length(valuesSet)
                    intervalValue = valuesSet(i);
                    
                    if isempty(app.IntegralOrderData) || ~any(app.IntegralOrderData(:,1)==intervalValue)
                       app.IntegralOrderData(end+1,:) = [intervalValue, 1];
                    end
                end
                if (~any(app.IntegralOrderData(:,1)==-Inf))
                    app.IntegralOrderData(end+1,:) = [-Inf, 1];
                end
                
                % Sort intevals array
                app.IntegralOrderData = sortrows(app.IntegralOrderData, 'descend');
                
                % Update the Integral intervals table
                app.IntegralOrdersTable.Data = getOrdersTableValues(app, app.IntegralOrderData);
             end
        end
        
        function addDerivativeIntervalValues(app, valuesSet)
            app.DerivativeOrdersTable.Data
            length(app.DerivativeOrdersTable.Data)
            if ~isempty(valuesSet)
                % Load orders data - only if the table is not empty
                if ~isempty(app.DerivativeOrdersTable.Data)
                    for i = 1:length(app.DerivativeOrdersTable.Data)
                        orderCell = app.DerivativeOrdersTable.Data(i, 2);
                        orderCellValue = orderCell{1};

                        if isempty(orderCellValue) || ~isnumeric(orderCellValue)
                            app.DerivativeOrderData(i, 2) = 1;
                        else
                            app.DerivativeOrderData(i, 2) = orderCellValue;
                        end
                    end
                end
               
                % Update intervals array
                for i = 1:length(valuesSet)
                    intervalValue = valuesSet(i);
                    
                    if isempty(app.DerivativeOrderData) || ~any(app.DerivativeOrderData(:,1)==intervalValue)
                       app.DerivativeOrderData(end+1,:) = [intervalValue, 1];
                    end
                end
                if (~any(app.DerivativeOrderData(:,1)==-Inf))
                    app.DerivativeOrderData(end+1,:) = [-Inf, 1];
                end
                
                % Sort intevals array
                app.DerivativeOrderData = sortrows(app.DerivativeOrderData, 'descend');
                
                % Update the Integral intervals table
                app.DerivativeOrdersTable.Data = getOrdersTableValues(app, app.DerivativeOrderData);
            end
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IntegralConstantOrderPanel.Visible = 'On';
            app.IntegralFunctionOfErrorPanel.Visible = 'Off';
            app.IntegralUserDefinedFunctionPanel.Visible = 'Off';
            
            app.DerivativeConstantOrderPanel.Visible = 'On';
            app.DerivativeFunctionOfErrorPanel.Visible = 'Off';
            app.DerivativeUserDefinedFunctionPanel.Visible = 'Off';
            
            disableCustomObjectiveFunctionGuiElements(app);
            
            app.IntegralOrdersTable.ColumnEditable(2) = true;
            app.IntegralOrdersTable.ColumnEditable(3) = true;
            
            app.DerivativeOrdersTable.ColumnEditable(2) = true;
            app.DerivativeOrdersTable.ColumnEditable(3) = true;
            
            app.IntegralOrderData = [];
            app.DerivativeOrderData = [];
            
            % Check if Global Optimization Toolbox is installed
            if ~contains(struct2array(ver), 'Global Optimization Toolbox')
                app.OptimizationMethodDropDown.Items = {'Nelder-Mead optimization', 'Particle swarm optimization (not supported)', 'Genetic algorithm (not supported)'};
            end
        end

        % Value changed function: LimitoutputCheckBox
        function LimitoutputCheckBoxValueChanged(app, event)
            valueSaturation = app.LimitoutputCheckBox.Value;
            valueDelay = app.TransportDelayEditField.Value;
            if ( valueSaturation )
                app.UpperlimitEditField.Enable = 'On';
                app.LowerlimitEditField.Enable = 'On';
                
                if ( valueDelay > 0 )
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchemaDelaySaturation.PNG';
                else 
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchemaSaturation.PNG';
                end
            else
                app.UpperlimitEditField.Enable = 'Off';
                app.LowerlimitEditField.Enable = 'Off';
                
                if ( valueDelay > 0 )
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchemaDelay.PNG';
                else
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchema.PNG';
                end
            end
        end

        % Value changed function: IntegralorderfunctiontypeDropDown
        function IntegralorderfunctiontypeDropDownValueChanged(app, event)
            value = app.IntegralorderfunctiontypeDropDown.Value;
            
            switch value
                case 'Constant order' 
                    app.IntegralConstantOrderPanel.Visible = 'on';
                    app.IntegralFunctionOfErrorPanel.Visible = 'off';
                    app.IntegralUserDefinedFunctionPanel.Visible = 'off';
                case 'Function of error'
                    app.IntegralConstantOrderPanel.Visible = 'off';
                    app.IntegralFunctionOfErrorPanel.Visible = 'on';
                    app.IntegralUserDefinedFunctionPanel.Visible = 'off';
                otherwise
                    app.IntegralConstantOrderPanel.Visible = 'off';
                    app.IntegralFunctionOfErrorPanel.Visible = 'off';
                    app.IntegralUserDefinedFunctionPanel.Visible = 'on'; 
            end
        end

        % Button pushed function: 
        % ControllerBasicParametersInfoButton
        function ControllerBasicParametersInfoButtonPushed(app, event)
            app.ControllerBasicParametersInfoButton.Enable = 'off';
            fvopidToolbox.app.infoFVOPIDWindow(app, app.ControllerBasicParametersInfoButton);
        end

        % Button pushed function: SaturationInfoButton
        function SaturationInfoButtonPushed(app, event)
            app.SaturationInfoButton.Enable = 'off';
            fvopidToolbox.app.infoSaturationWindow(app, app.SaturationInfoButton);
        end

        % Button pushed function: PlantInfoButton
        function PlantInfoButtonPushed(app, event)
            app.PlantInfoButton.Enable = 'off';
            fvopidToolbox.app.infoPlantWindow(app, app.PlantInfoButton);
        end

        % Value changed function: DiscretePlantCheckBox
        function DiscretePlantCheckBoxValueChanged(app, event)
            value = app.DiscretePlantCheckBox.Value;
            
            if ( value )
                app.DiscreteplantsampletimesEditField.Enable = 'On';
            else
                app.DiscreteplantsampletimesEditField.Enable = 'Off';
            end
        end

        % Button pushed function: ShowTransferFunctionButton
        function ShowTransferFunctionButtonPushed(app, event)
             nominatorValue = app.PlanttransferfunctionnumeratorEditField.Value;
             denominatorValue = app.PlanttransferfunctiondenominatorEditField.Value;
            
             nominatorVector = NaN;
             try
                nominatorVector = str2num(nominatorValue);
             catch exception
                     errordlg('Error occured during nominator vector processing! It should be a vector of finit double numbers e.g. [1, 2, 3].',...
                         'Incorrect nominator processing error!');
             end
             
             denominatorVector = NaN;
             try
                denominatorVector = str2num(denominatorValue);
             catch exception
                     errordlg('Error occured during denominator vector processing! It should be a vector of finit double numbers e.g. [1, 2, 3].',...
                         'Incorrect nominator processing error!');
             end
             
             if all(~isnan(nominatorVector)) && all(~isnan(denominatorVector))
                 app.ShowTransferFunctionButton.Enable = 'off';
                 samplingTime = Inf;
                 discretePlant = app.DiscretePlantCheckBox.Value;
                 if ( discretePlant )
                     samplingTime = app.DiscreteplantsampletimesEditField.Value;
                 end
                 fvopidToolbox.app.transferFunctionInformationWindow(app, app.ShowTransferFunctionButton,...
                    nominatorVector, denominatorVector, samplingTime); 
             end
        end

        % Value changed function: 
        % PlanttransferfunctionnumeratorEditField
        function PlanttransferfunctionnumeratorEditFieldValueChanged(app, event)
            value = app.PlanttransferfunctionnumeratorEditField.Value;
            
             try
                nominatorVector = str2num(value);
             catch exception
                     errordlg('Error occured during nominator vector processing! It should be a vector of finit double numbers e.g. [1, 2, 3].',...
                         'Incorrect nominator processing error!');
             end
        end

        % Value changed function: 
        % PlanttransferfunctiondenominatorEditField
        function PlanttransferfunctiondenominatorEditFieldValueChanged(app, event)
            value = app.PlanttransferfunctiondenominatorEditField.Value;
            
            try
                denominatorVector = str2num(value);
             catch exception
                     errordlg('Error occured during denominator vector processing! It should be a vector of finit double numbers e.g. [1, 2, 3].',...
                         'Incorrect nominator processing error!');
             end
        end

        % Value changed function: 
        % DerivativeorderfunctiontypeDropDown
        function DerivativeorderfunctiontypeDropDownValueChanged(app, event)
            value = app.DerivativeorderfunctiontypeDropDown.Value;
            
            switch value
                case 'Constant order' 
                    app.DerivativeConstantOrderPanel.Visible = 'on';
                    app.DerivativeFunctionOfErrorPanel.Visible = 'off';
                    app.DerivativeUserDefinedFunctionPanel.Visible = 'off';
                case 'Function of error'
                    app.DerivativeConstantOrderPanel.Visible = 'off';
                    app.DerivativeFunctionOfErrorPanel.Visible = 'on';
                    app.DerivativeUserDefinedFunctionPanel.Visible = 'off';
                otherwise
                    app.DerivativeConstantOrderPanel.Visible = 'off';
                    app.DerivativeFunctionOfErrorPanel.Visible = 'off';
                    app.DerivativeUserDefinedFunctionPanel.Visible = 'on'; 
            end
        end

        % Value changed function: MinimizedObjectiveFunctionDropDown
        function MinimizedObjectiveFunctionDropDownValueChanged(app, event)
            value = app.MinimizedObjectiveFunctionDropDown.Value;
            
            switch value
                case 'Custom objective function' 
                    enableCustomObjectiveFunctionGuiElements(app);
                otherwise
                    disableCustomObjectiveFunctionGuiElements(app);
            end
        end

        % Button pushed function: ShowSystemResponseButton
        function ShowSystemResponseButtonPushed(app, event)
            [validationCorrect, errorMessage] = validateControllerSetup(app);
            
            if validationCorrect  
                f = uifigure;
                progressDialog = uiprogressdlg(f,'Title','Calculating system response...', 'Indeterminate','on');
                
                app.ShowSystemResponseButton.Enable = 'Off';
                oldText = 'Show system response';
                app.ShowSystemResponseButton.Text = 'Generating response...';
                
                try
                    % Create objects which describe closed loop system setup
                    [closedLoopSystemSetupObject, controllerSetupObject, controllerParameters] = createClosedLoopSetupObjects(app);
                        
                    % Open window with the results
                    fvopidToolbox.app.stepResponseAxesWindow(closedLoopSystemSetupObject, controllerSetupObject, controllerParameters);
                    
                    % Set the button back to initial state
                    app.ShowSystemResponseButton.Enable = 'On';
                    app.ShowSystemResponseButton.Text = oldText;
                    close(progressDialog);
                    close(f);
                catch Ex
                    app.ShowSystemResponseButton.Enable = 'On';
                    app.ShowSystemResponseButton.Text = oldText;
                    close(progressDialog);
                    close(f);
                    rethrow(Ex);
                end
            else
                fig = uifigure;
                uialert(fig, errorMessage, 'Controller setup error');
            end
        end

        % Button pushed function: ParametersSearchingButton
        function ParametersSearchingButtonPushed(app, event)
            [validationCorrect, errorMessage] = validateControllerSetup(app);
            
            if validationCorrect
                [optimizationValidationCorrect, errorMessage] = validateOptimizationSetup(app);
                if ~optimizationValidationCorrect
                    fig = uifigure;
                    uialert(fig, errorMessage, 'Optimization process setup error');
                else
                    app.ParametersSearchingButton.Enable = 'Off';
                    
                    try
                        % Create objects which describe closed loop system setup
                        [closedLoopSystemSetupObject, controllerSetupObject, controllerParameters] = createClosedLoopSetupObjects(app);
                        objectiveFunctionSetupObject = createObjectiveFunctionSetupObject(app);
                        
                        optimizationMethodType = app.OptimizationMethodDropDown.Value;
                        switch optimizationMethodType
                            case 'Nelder-Mead optimization'
                                % Open Nelder-Mead parameters search window
                                fvopidToolbox.app.nelderMeadParametersSearchWindow(closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject);
                            case 'Particle swarm optimization'
                                if contains(struct2array(ver), 'Global Optimization Toolbox')
                                    % Open particle swarm parameters search window
                                    fvopidToolbox.app.particleSwarmParametersSearchWindow(closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject);
                                else
                                    errordlg('Particle swarm optimization requires Global Optimization Toolbox which is not installed!',...
                                        'Optimization method not supported!');
                                end
                            otherwise
                                if contains(struct2array(ver), 'Global Optimization Toolbox')
                                    % Open genetic algorithm parameters search window
                                    fvopidToolbox.app.geneticAlgorithmParametersSearchWindow(closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject); 
                                else
                                    errordlg('Genetic algorithm requires Global Optimization Toolbox which is not installed!',...
                                        'Optimization method not supported!');
                                end
                        end
                        
                        app.ParametersSearchingButton.Enable = 'On';
                    catch Ex
                        app.ParametersSearchingButton.Enable = 'On';
                        
                        rethrow(Ex);
                    end
                end
            else
                fig = uifigure;
                uialert(fig, errorMessage, 'Controller setup error');
            end
        end

        % Callback function
        function UseCustomInitializationCodeCheckBoxValueChanged(app, event)
            value = app.UseCustomInitializationCodeCheckBox.Value;
            
            if ( value )
                enableCustomInitializationSetupGuiElements(app);
            else
                disableCustomInitializationSetupGuiElements(app);
            end
        end

        % Button pushed function: 
        % CalculateSelectedObjectiveFunctionButton
        function CalculateSelectedObjectiveFunctionButtonPushed(app, event)
            
            f = uifigure;
            progressDialog = uiprogressdlg(f,'Title','Calculating selected objective function value...', 'Indeterminate','on');

            try
                % Create objects which describe closed loop system setup
                [closedLoopSystemSetupObject, controllerSetupObject, controllerParameters] = createClosedLoopSetupObjects(app);
                objectiveFunctionSetupObject = createObjectiveFunctionSetupObject(app);
                
                % Open window with calculated objective function value
                fvopidToolbox.app.objectiveFunctionWindow(closedLoopSystemSetupObject, controllerSetupObject, controllerParameters, objectiveFunctionSetupObject);
                close(progressDialog);
                close(f);
            catch Ex
                close(progressDialog);
                close(f);
                
                rethrow(Ex);
            end
        end

        % Value changed function: TransportDelayEditField
        function TransportDelayEditFieldValueChanged(app, event)
            valueSaturation = app.LimitoutputCheckBox.Value;
            valueDelay = app.TransportDelayEditField.Value;
            
            if ( valueDelay > 0 )
                if ( valueSaturation ) 
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchemaDelaySaturation.PNG';
                else
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchemaSaturation.PNG';
                end
            else
                if ( valueSaturation ) 
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchemaSaturation.PNG';
                else
                    app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchema.PNG';
                end
            end
        end

        % Button pushed function: AddOrderButton
        function AddOrderButtonPushed(app, event)
            functionHandle = @(valuesSet)addIntegralIntervalValues(app, valuesSet);
            fvopidToolbox.app.addIntervalValue(functionHandle);
        end

        % Button pushed function: RemoveOrderButton
        function RemoveOrderButtonPushed(app, event)
            % Load orders data - only if the table is not empty
            % TODO: refactoring needed!!!
            if ~isempty(app.IntegralOrdersTable.Data)
                for i = 1:length(app.IntegralOrdersTable.Data)
                    orderCell = app.IntegralOrdersTable.Data(i, 2);
                    orderCellValue = orderCell{1};

                    if isempty(orderCellValue) || ~isnumeric(orderCellValue)
                        app.IntegralOrderData(i, 2) = NaN;
                    else
                        app.IntegralOrderData(i, 2) = orderCellValue;
                    end
                end
            end
            
            % Check which rows were selected
            notSelectedIndexes = [];
            for i = 1:length(app.IntegralOrdersTable.Data) - 1
                rowSelectedCellArray = app.IntegralOrdersTable.Data(i, 3);
                rowSelected = rowSelectedCellArray{1};
                if ~rowSelected
                    notSelectedIndexes = [notSelectedIndexes i];
                end
            end
            notSelectedIndexes = [notSelectedIndexes length(app.IntegralOrdersTable.Data)];
            
            app.IntegralOrderData = app.IntegralOrderData(notSelectedIndexes, :);
            
            app.IntegralOrdersTable.Data = getOrdersTableValues(app, app.IntegralOrderData);
        end

        % Value changed function: 
        % IntegralUseErrorToSetpointRatioCheckBox
        function IntegralUseErrorToSetpointRatioCheckBoxValueChanged(app, event)
            value = app.IntegralUseErrorToSetpointRatioCheckBox.Value;
            
            if value
                app.IntegralOrdersTable.ColumnName = {'Error/Setpoint', 'Order Value', ''};
            else
                app.IntegralOrdersTable.ColumnName = {'Error', 'Order Value', ''};
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            for i = 1:length(app.IntegralOrdersTable.Data)
                app.IntegralOrdersTable.Data(i, 3) = {true};
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            for i = 1:length(app.IntegralOrdersTable.Data)
                app.IntegralOrdersTable.Data(i, 3) = {false};
            end
        end

        % Button pushed function: DerviativeAddOrderButton
        function DerviativeAddOrderButtonPushed(app, event)
            functionHandle = @(valuesSet)addDerivativeIntervalValues(app, valuesSet);
            fvopidToolbox.app.addIntervalValue(functionHandle);
        end

        % Button pushed function: DerivativeRemoveOrderButton
        function DerivativeRemoveOrderButtonPushed(app, event)
            % TODO: refactoring needed!!!
            if ~isempty(app.DerivativeOrdersTable.Data)
                for i = 1:length(app.DerivativeOrdersTable.Data)
                    orderCell = app.DerivativeOrdersTable.Data(i, 2);
                    orderCellValue = orderCell{1};

                    if isempty(orderCellValue) || ~isnumeric(orderCellValue)
                        app.DerivativeOrderData(i, 2) = NaN;
                    else
                        app.DerivativeOrderData(i, 2) = orderCellValue;
                    end
                end
            end
            
            notSelectedIndexes = [];
            for i = 1:length(app.DerivativeOrdersTable.Data) - 1
                rowSelectedCellArray = app.DerivativeOrdersTable.Data(i, 3);
                rowSelected = rowSelectedCellArray{1};
                if ~rowSelected
                    notSelectedIndexes = [notSelectedIndexes i];
                end
            end
            notSelectedIndexes = [notSelectedIndexes length(app.DerivativeOrdersTable.Data)];
            
            app.DerivativeOrderData = app.DerivativeOrderData(notSelectedIndexes, :);
            
            app.DerivativeOrdersTable.Data = getOrdersTableValues(app, app.DerivativeOrderData);
        end

        % Button pushed function: DerivativeSelectAllButton
        function DerivativeSelectAllButtonPushed(app, event)
            for i = 1:length(app.DerivativeOrdersTable.Data)
                app.DerivativeOrdersTable.Data(i, 3) = {true};
            end
        end

        % Button pushed function: DerivativeClearAllButton
        function DerivativeClearAllButtonPushed(app, event)
            for i = 1:length(app.DerivativeOrdersTable.Data)
                app.DerivativeOrdersTable.Data(i, 3) = {false};
            end
        end

        % Value changed function: 
        % DerivativeUseErrorToSetpointRatioCheckBox
        function DerivativeUseErrorToSetpointRatioCheckBoxValueChanged(app, event)
            value = app.DerivativeUseErrorToSetpointRatioCheckBox.Value;
            
            if value
                app.DerivativeOrdersTable.ColumnName = {'Error/Setpoint', 'Order Value', ''};
            else
                app.DerivativeOrdersTable.ColumnName = {'Error', 'Order Value', ''};
            end
        end

        % Button pushed function: OptimizedMethodInfoButton
        function OptimizedMethodInfoButtonPushed(app, event)
            app.OptimizedMethodInfoButton.Enable = 'off';
            fvopidToolbox.app.infoOptimizationMethodsGeneralWindow(app, app.OptimizedMethodInfoButton);
        end

        % Button pushed function: 
        % MinimizedObjectiveFunctionInfoButton
        function MinimizedObjectiveFunctionInfoButtonPushed(app, event)
            app.MinimizedObjectiveFunctionInfoButton.Enable = 'off';
            fvopidToolbox.app.infoObjectiveFunctionWindow(app, app.MinimizedObjectiveFunctionInfoButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FractionalvariableorderclosedloopsystemssolverLabel = uilabel(app.UIFigure);
            app.FractionalvariableorderclosedloopsystemssolverLabel.Text = 'Fractional-variable-order closed loop systems solver';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClosedloopsystemsetupTab = uitab(app.TabGroup);
            app.ClosedloopsystemsetupTab.Title = 'Closed loop system setup';
            app.ControllerbasicparametersPanel = uipanel(app.ClosedloopsystemsetupTab);
            app.ControllerbasicparametersPanel.Title = 'Controller basic parameters';
            app.ControllertypeLabel = uilabel(app.ControllerbasicparametersPanel);
            app.ControllertypeLabel.Text = 'Controller type:';
            app.ControllerTypeDropDown = uidropdown(app.ControllerbasicparametersPanel);
            app.ControllerTypeDropDown.Items = {'FVOPID Type A', 'FVOPID Convolution Type'};
            app.ControllerTypeDropDown.Value = 'FVOPID Type A';
            app.ControllerBasicParametersInfoButton = uibutton(app.ControllerbasicparametersPanel, 'push');
            app.ControllerBasicParametersInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ControllerBasicParametersInfoButtonPushed, true);
            app.ControllerBasicParametersInfoButton.Icon = 'information.png';
            app.ControllerBasicParametersInfoButton.Text = '';
            app.ProportionalPEditFieldLabel = uilabel(app.ControllerbasicparametersPanel);
            app.ProportionalPEditFieldLabel.Text = 'Proportional (P):';
            app.KpEditField = uieditfield(app.ControllerbasicparametersPanel, 'numeric');
            app.KpEditField.UpperLimitInclusive = 'off';
            app.KpEditField.Limits = [-1 Inf];
            app.KpEditField.Value = 1;
            app.IntegralIEditFieldLabel = uilabel(app.ControllerbasicparametersPanel);
            app.IntegralIEditFieldLabel.Text = 'Integral (I):';
            app.KiEditField = uieditfield(app.ControllerbasicparametersPanel, 'numeric');
            app.KiEditField.UpperLimitInclusive = 'off';
            app.KiEditField.Limits = [-1 Inf];
            app.KiEditField.Value = 1;
            app.DerivativeDEditFieldLabel = uilabel(app.ControllerbasicparametersPanel);
            app.DerivativeDEditFieldLabel.Text = 'Derivative (D):';
            app.KdEditField = uieditfield(app.ControllerbasicparametersPanel, 'numeric');
            app.KdEditField.UpperLimitInclusive = 'off';
            app.KdEditField.Limits = [-1 Inf];
            app.KdEditField.Value = 1;
            app.ControllersamplingtimesEditFieldLabel = uilabel(app.ControllerbasicparametersPanel);
            app.ControllersamplingtimesEditFieldLabel.Text = 'Controller sampling time [s]:';
            app.ControllersamplingtimesEditField = uieditfield(app.ControllerbasicparametersPanel, 'numeric');
            app.ControllersamplingtimesEditField.LowerLimitInclusive = 'off';
            app.ControllersamplingtimesEditField.UpperLimitInclusive = 'off';
            app.ControllersamplingtimesEditField.Value = 1;
            app.SaturationPanel = uipanel(app.ClosedloopsystemsetupTab);
            app.SaturationPanel.Title = 'Saturation';
            app.LimitoutputCheckBox = uicheckbox(app.SaturationPanel);
            app.LimitoutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitoutputCheckBoxValueChanged, true);
            app.LimitoutputCheckBox.Text = 'Limit output';
            app.UpperlimitEditFieldLabel = uilabel(app.SaturationPanel);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit:';
            app.UpperlimitEditField = uieditfield(app.SaturationPanel, 'numeric');
            app.UpperlimitEditField.Enable = 'off';
            app.UpperlimitEditField.Value = Inf;
            app.LowerlimitEditFieldLabel = uilabel(app.SaturationPanel);
            app.LowerlimitEditFieldLabel.Text = 'Lower limit:';
            app.LowerlimitEditField = uieditfield(app.SaturationPanel, 'numeric');
            app.LowerlimitEditField.Enable = 'off';
            app.LowerlimitEditField.Value = -Inf;
            app.SaturationInfoButton = uibutton(app.SaturationPanel, 'push');
            app.SaturationInfoButton.ButtonPushedFcn = createCallbackFcn(app, @SaturationInfoButtonPushed, true);
            app.SaturationInfoButton.Icon = 'information.png';
            app.SaturationInfoButton.Text = '';
            app.PlantsimulationsetupPanel = uipanel(app.ClosedloopsystemsetupTab);
            app.PlantsimulationsetupPanel.Title = 'Plant & simulation setup';
            app.ShowTransferFunctionButton = uibutton(app.PlantsimulationsetupPanel, 'push');
            app.ShowTransferFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTransferFunctionButtonPushed, true);
            app.ShowTransferFunctionButton.Text = 'Show';
            app.PlantInfoButton = uibutton(app.PlantsimulationsetupPanel, 'push');
            app.PlantInfoButton.ButtonPushedFcn = createCallbackFcn(app, @PlantInfoButtonPushed, true);
            app.PlantInfoButton.Icon = 'information.png';
            app.PlantInfoButton.Text = '';
            app.DiscretePlantCheckBox = uicheckbox(app.PlantsimulationsetupPanel);
            app.DiscretePlantCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiscretePlantCheckBoxValueChanged, true);
            app.DiscretePlantCheckBox.Text = 'Discrete Plant';
            app.DiscreteplantsampletimesEditFieldLabel = uilabel(app.PlantsimulationsetupPanel);
            app.DiscreteplantsampletimesEditFieldLabel.Text = 'Discrete plant sample time [s]:';
            app.DiscreteplantsampletimesEditField = uieditfield(app.PlantsimulationsetupPanel, 'numeric');
            app.DiscreteplantsampletimesEditField.LowerLimitInclusive = 'off';
            app.DiscreteplantsampletimesEditField.UpperLimitInclusive = 'off';
            app.DiscreteplantsampletimesEditField.Enable = 'off';
            app.DiscreteplantsampletimesEditField.Value = -1;
            app.SimulationtimesEditFieldLabel = uilabel(app.PlantsimulationsetupPanel);
            app.SimulationtimesEditFieldLabel.Text = 'Simulation time [s]:';
            app.SimulationtimesEditField = uieditfield(app.PlantsimulationsetupPanel, 'numeric');
            app.SimulationtimesEditField.LowerLimitInclusive = 'off';
            app.SimulationtimesEditField.UpperLimitInclusive = 'off';
            app.SimulationtimesEditField.Limits = [0 Inf];
            app.SimulationtimesEditField.Value = 1;
            app.PlanttransferfunctionnumeratorEditFieldLabel = uilabel(app.PlantsimulationsetupPanel);
            app.PlanttransferfunctionnumeratorEditFieldLabel.Text = 'Plant transfer function numerator:';
            app.PlanttransferfunctionnumeratorEditField = uieditfield(app.PlantsimulationsetupPanel, 'text');
            app.PlanttransferfunctionnumeratorEditField.ValueChangedFcn = createCallbackFcn(app, @PlanttransferfunctionnumeratorEditFieldValueChanged, true);
            app.PlanttransferfunctionnumeratorEditField.Value = '[1]';
            app.ClosedloopsystemsetpointvalueEditFieldLabel = uilabel(app.PlantsimulationsetupPanel);
            app.ClosedloopsystemsetpointvalueEditFieldLabel.Text = 'Closed loop system setpoint value:';
            app.ClosedloopsystemsetpointvalueEditField = uieditfield(app.PlantsimulationsetupPanel, 'numeric');
            app.ClosedloopsystemsetpointvalueEditField.LowerLimitInclusive = 'off';
            app.ClosedloopsystemsetpointvalueEditField.UpperLimitInclusive = 'off';
            app.ClosedloopsystemsetpointvalueEditField.Value = 1;
            app.PlanttransferfunctiondenominatorEditFieldLabel = uilabel(app.PlantsimulationsetupPanel);
            app.PlanttransferfunctiondenominatorEditFieldLabel.Text = 'Plant transfer function denominator:';
            app.PlanttransferfunctiondenominatorEditField = uieditfield(app.PlantsimulationsetupPanel, 'text');
            app.PlanttransferfunctiondenominatorEditField.ValueChangedFcn = createCallbackFcn(app, @PlanttransferfunctiondenominatorEditFieldValueChanged, true);
            app.PlanttransferfunctiondenominatorEditField.Value = '[1,1]';
            app.GeneratedoutputsamplingtimesLabel = uilabel(app.PlantsimulationsetupPanel);
            app.GeneratedoutputsamplingtimesLabel.Text = 'Generated output sampling time [s]:';
            app.GeneratedOutputSamplingTimeEditField = uieditfield(app.PlantsimulationsetupPanel, 'numeric');
            app.GeneratedOutputSamplingTimeEditField.Value = -1;
            app.TransportdelaysEditFieldLabel = uilabel(app.PlantsimulationsetupPanel);
            app.TransportdelaysEditFieldLabel.Text = 'Transport delay [s]:';
            app.TransportDelayEditField = uieditfield(app.PlantsimulationsetupPanel, 'numeric');
            app.TransportDelayEditField.UpperLimitInclusive = 'off';
            app.TransportDelayEditField.Limits = [0 Inf];
            app.TransportDelayEditField.ValueChangedFcn = createCallbackFcn(app, @TransportDelayEditFieldValueChanged, true);
            app.ClosedLoopSystemImageButton = uibutton(app.ClosedloopsystemsetupTab, 'push');
            app.ClosedLoopSystemImageButton.Icon = 'closedLoopSchema.PNG';
            app.ClosedLoopSystemImageButton.Text = '';
            app.IntegralorderTab = uitab(app.TabGroup);
            app.IntegralorderTab.Title = 'Integral order';
            app.IntegralorderfunctiontypeDropDownLabel = uilabel(app.IntegralorderTab);
            app.IntegralorderfunctiontypeDropDownLabel.Text = 'Integral order function type:';
            app.IntegralorderfunctiontypeDropDown = uidropdown(app.IntegralorderTab);
            app.IntegralorderfunctiontypeDropDown.Items = {'Constant order', 'Function of error', 'User defined function'};
            app.IntegralorderfunctiontypeDropDown.ValueChangedFcn = createCallbackFcn(app, @IntegralorderfunctiontypeDropDownValueChanged, true);
            app.IntegralorderfunctiontypeDropDown.Value = 'Constant order';
            app.IntegralUserDefinedFunctionPanel = uipanel(app.IntegralorderTab);
            app.IntegralUserDefinedFunctionPanel.Title = 'User defined function';
            app.Label_3 = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.Label_3.Text = '';
            app.IntegralOrderFunctionTextArea = uitextarea(app.IntegralUserDefinedFunctionPanel);
            app.IntegralOrderFunctionTextArea.Value = {'y = 1;    %Always assign the result to variable ''y'' '};
            app.Label_4 = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.Label_4.Text = 'y = integralOrderFunction(error, setpoint, currentTime, parametersVector)';
            app.endLabel = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.endLabel.Text = 'end';
            app.Label_5 = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.Label_5.Text = 'Provide a body of function which should be used to generate integral order values. The function';
            app.returnsvalueassignedtovariableyandhasthefollowingargumentsLabel = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.returnsvalueassignedtovariableyandhasthefollowingargumentsLabel.Text = 'returns value assigned to variable y and has the following arguments:';
            app.errorcurrentcontrolerrorvalueLabel = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.errorcurrentcontrolerrorvalueLabel.Text = '- error - current control error value,';
            app.setpointcurrentvalueofthesetpointLabel = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.setpointcurrentvalueofthesetpointLabel.Text = '- setpoint - current value of the setpoint,';
            app.currentTimethesimulationtimeLabel = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.currentTimethesimulationtimeLabel.Text = '- currentTime - the simulation time,';
            app.parametersVectoruserdefinedparametersLabel = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.parametersVectoruserdefinedparametersLabel.Text = '- parametersVector - user defined parameters.';
            app.Label_6 = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.Label_6.Text = 'Additionally provide the values of parametersVector (this is a vector of parameters which are ';
            app.searchedbytheoptimizationalgorithmLabel = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.searchedbytheoptimizationalgorithmLabel.Text = 'searched by the optimization algorithm).';
            app.ParametersvectorLabel_2 = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.ParametersvectorLabel_2.Text = 'Parameters vector:';
            app.IntegralUserDefinedFunctionParametersVectorEditField_2 = uieditfield(app.IntegralUserDefinedFunctionPanel, 'text');
            app.IntegralUserDefinedFunctionParametersVectorEditField_2.Value = '[1,1]';
            app.IntegralWarningLabel1 = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.IntegralWarningLabel1.Text = 'The result of function invocation should always be assigned to variable ''y'', e.g.:';
            app.IntegralWarningLabel2 = uilabel(app.IntegralUserDefinedFunctionPanel);
            app.IntegralWarningLabel2.Text = '''y = 1'' returns constant order 1, while ''y = currentTime'' returns order equal to time in seconds.';
            app.IntegralFunctionOfErrorPanel = uipanel(app.IntegralorderTab);
            app.IntegralFunctionOfErrorPanel.Title = 'Function of error';
            app.Label = uilabel(app.IntegralFunctionOfErrorPanel);
            app.Label.Text = 'Provide the values of control errors and the corresponding values of orders.';
            app.IntegralUseErrorToSetpointRatioCheckBox = uicheckbox(app.IntegralFunctionOfErrorPanel);
            app.IntegralUseErrorToSetpointRatioCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegralUseErrorToSetpointRatioCheckBoxValueChanged, true);
            app.IntegralUseErrorToSetpointRatioCheckBox.Text = 'Use error/setpoint ratio';
            app.IntegralOrdersTable = uitable(app.IntegralFunctionOfErrorPanel);
            app.IntegralOrdersTable.ColumnName = {'Error'; 'Order Value'; ' '};
            app.IntegralOrdersTable.RowName = {};
            app.AddOrderButton = uibutton(app.IntegralFunctionOfErrorPanel, 'push');
            app.AddOrderButton.ButtonPushedFcn = createCallbackFcn(app, @AddOrderButtonPushed, true);
            app.AddOrderButton.Text = 'Add Error Values';
            app.RemoveOrderButton = uibutton(app.IntegralFunctionOfErrorPanel, 'push');
            app.RemoveOrderButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOrderButtonPushed, true);
            app.RemoveOrderButton.Text = 'Remove Selected Entries';
            app.SelectAllButton = uibutton(app.IntegralFunctionOfErrorPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.ClearAllButton = uibutton(app.IntegralFunctionOfErrorPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.Label_13 = uilabel(app.IntegralFunctionOfErrorPanel);
            app.Label_13.Text = 'When ''Use error/setpoint ratio'' is selected then instead of error the ratio of error to the setpoint is used';
            app.todeterminethevalueoforderLabel = uilabel(app.IntegralFunctionOfErrorPanel);
            app.todeterminethevalueoforderLabel.Text = 'to determine the value of order.';
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_4 = uilabel(app.IntegralFunctionOfErrorPanel);
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_4.Text = '* If negative value of order is provided then for selected error itntegral term works as derivative';
            app.IntegralConstantOrderPanel = uipanel(app.IntegralorderTab);
            app.IntegralConstantOrderPanel.Title = 'Constant order';
            app.ThecontrollerwillhaveaconstantintegralordervalueLabel = uilabel(app.IntegralConstantOrderPanel);
            app.ThecontrollerwillhaveaconstantintegralordervalueLabel.Text = 'The controller will have a constant integral order value.';
            app.IntegralorderEditFieldLabel = uilabel(app.IntegralConstantOrderPanel);
            app.IntegralorderEditFieldLabel.Text = 'Integral order:';
            app.IntegralOrderEditField = uieditfield(app.IntegralConstantOrderPanel, 'numeric');
            app.IntegralOrderEditField.LowerLimitInclusive = 'off';
            app.IntegralOrderEditField.UpperLimitInclusive = 'off';
            app.IntegralOrderEditField.Value = 1;
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_5 = uilabel(app.IntegralConstantOrderPanel);
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_5.Text = '* If negative value of order is provided then integral term works as derivative';
            app.DerivativeorderTab = uitab(app.TabGroup);
            app.DerivativeorderTab.Title = 'Derivative order';
            app.DerivativeorderfunctiontypetypeDropDown_2Label = uilabel(app.DerivativeorderTab);
            app.DerivativeorderfunctiontypetypeDropDown_2Label.Text = 'Derivative order function type type:';
            app.DerivativeorderfunctiontypeDropDown = uidropdown(app.DerivativeorderTab);
            app.DerivativeorderfunctiontypeDropDown.Items = {'Constant order', 'Function of error', 'User defined function'};
            app.DerivativeorderfunctiontypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DerivativeorderfunctiontypeDropDownValueChanged, true);
            app.DerivativeorderfunctiontypeDropDown.Value = 'Constant order';
            app.DerivativeConstantOrderPanel = uipanel(app.DerivativeorderTab);
            app.DerivativeConstantOrderPanel.Title = 'Constant order';
            app.ThecontrollerwillhaveaconstantintegralordervalueLabel_2 = uilabel(app.DerivativeConstantOrderPanel);
            app.ThecontrollerwillhaveaconstantintegralordervalueLabel_2.Text = 'The controller will have a constant integral order value.';
            app.DerivativeorderEditFieldLabel = uilabel(app.DerivativeConstantOrderPanel);
            app.DerivativeorderEditFieldLabel.Text = 'Derivative order:';
            app.DerivativeorderEditField = uieditfield(app.DerivativeConstantOrderPanel, 'numeric');
            app.DerivativeorderEditField.LowerLimitInclusive = 'off';
            app.DerivativeorderEditField.UpperLimitInclusive = 'off';
            app.DerivativeorderEditField.Value = 1;
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_6 = uilabel(app.DerivativeConstantOrderPanel);
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_6.Text = '* If negative value of order is provided then derivative term works as integral';
            app.DerivativeUserDefinedFunctionPanel = uipanel(app.DerivativeorderTab);
            app.DerivativeUserDefinedFunctionPanel.Title = 'User defined function';
            app.TextArea_2Label = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.TextArea_2Label.Text = '';
            app.DervativeOrderFunctionTextArea = uitextarea(app.DerivativeUserDefinedFunctionPanel);
            app.DervativeOrderFunctionTextArea.Value = {'y = 1;    %Always assign the result to variable ''y'' '};
            app.Label_7 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.Label_7.Text = 'y = derivativeOrderFunction(error, setpoint, currentTime, parametersVector)';
            app.Label_8 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.Label_8.Text = 'Provide a definition of function which should be used to generate derivative order values. The function';
            app.hasthefollowingargumentsLabel_2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.hasthefollowingargumentsLabel_2.Text = 'has the following arguments:';
            app.errorcurrentcontrolerrorvalueLabel_2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.errorcurrentcontrolerrorvalueLabel_2.Text = '- error - current control error value,';
            app.setpointcurrentvalueofthesetpointLabel_2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.setpointcurrentvalueofthesetpointLabel_2.Text = '- setpoint - current value of the setpoint,';
            app.currentTimethesimulationtimeLabel_2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.currentTimethesimulationtimeLabel_2.Text = '- currentTime - the simulation time,';
            app.parametersVectoruserdefinedparametersLabel_2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.parametersVectoruserdefinedparametersLabel_2.Text = '- parametersVector - user defined parameters.';
            app.Label_9 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.Label_9.Text = 'Additionally provide the values of parametersVector (this is a vector of parameters which are ';
            app.searchedbytheoptimizationalgorithmLabel_2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.searchedbytheoptimizationalgorithmLabel_2.Text = 'searched by the optimization algorithm).';
            app.ParametersvectorLabel = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.ParametersvectorLabel.Text = 'Parameters vector:';
            app.DerivativeUserDefinedFunctionParametersVectorEditField = uieditfield(app.DerivativeUserDefinedFunctionPanel, 'text');
            app.DerivativeUserDefinedFunctionParametersVectorEditField.Value = '[1,1]';
            app.endLabel_2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.endLabel_2.Text = 'end';
            app.DerivativeWarningLabel1 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.DerivativeWarningLabel1.Text = 'The result of function invocation should always be assigned to variable ''y'', e.g.:';
            app.DerivativeWarningLabel2 = uilabel(app.DerivativeUserDefinedFunctionPanel);
            app.DerivativeWarningLabel2.Text = '''y = 1'' returns constant order 1, while ''y = currentTime'' returns order equal to time in seconds.';
            app.DerivativeFunctionOfErrorPanel = uipanel(app.DerivativeorderTab);
            app.DerivativeFunctionOfErrorPanel.Title = 'Function of error';
            app.Label_10 = uilabel(app.DerivativeFunctionOfErrorPanel);
            app.Label_10.Text = 'Provide the values of control errors and the corresponding values of orders.';
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_2 = uilabel(app.DerivativeFunctionOfErrorPanel);
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_2.Text = 'When ''Use error/setpoint ratio'' is selected then instead of error the ratio of error to the setpoint is used';
            app.Label_11 = uilabel(app.DerivativeFunctionOfErrorPanel);
            app.Label_11.Text = 'to determine the value of order.';
            app.DerivativeUseErrorToSetpointRatioCheckBox = uicheckbox(app.DerivativeFunctionOfErrorPanel);
            app.DerivativeUseErrorToSetpointRatioCheckBox.ValueChangedFcn = createCallbackFcn(app, @DerivativeUseErrorToSetpointRatioCheckBoxValueChanged, true);
            app.DerivativeUseErrorToSetpointRatioCheckBox.Text = 'Use error/setpoint ratio';
            app.DerivativeOrdersTable = uitable(app.DerivativeFunctionOfErrorPanel);
            app.DerivativeOrdersTable.ColumnName = {'Error'; 'Order Value'; ' '};
            app.DerivativeOrdersTable.RowName = {};
            app.DerviativeAddOrderButton = uibutton(app.DerivativeFunctionOfErrorPanel, 'push');
            app.DerviativeAddOrderButton.ButtonPushedFcn = createCallbackFcn(app, @DerviativeAddOrderButtonPushed, true);
            app.DerviativeAddOrderButton.Text = 'Add Error Value';
            app.DerivativeRemoveOrderButton = uibutton(app.DerivativeFunctionOfErrorPanel, 'push');
            app.DerivativeRemoveOrderButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeRemoveOrderButtonPushed, true);
            app.DerivativeRemoveOrderButton.Text = 'Remove Selected Entries';
            app.DerivativeSelectAllButton = uibutton(app.DerivativeFunctionOfErrorPanel, 'push');
            app.DerivativeSelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeSelectAllButtonPushed, true);
            app.DerivativeSelectAllButton.Text = 'Select All';
            app.DerivativeClearAllButton = uibutton(app.DerivativeFunctionOfErrorPanel, 'push');
            app.DerivativeClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeClearAllButtonPushed, true);
            app.DerivativeClearAllButton.Text = 'Clear All';
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_3 = uilabel(app.DerivativeFunctionOfErrorPanel);
            app.haveoneelementmorethanthevectoroferrorEgforifthevectorofLabel_3.Text = '* If negative value of order is provided then for selected error derivative term works as integral';
            app.OptimizationsetupTab = uitab(app.TabGroup);
            app.OptimizationsetupTab.Title = 'Optimization setup';
            app.OptimizationmethodDropDownLabel = uilabel(app.OptimizationsetupTab);
            app.OptimizationmethodDropDownLabel.Text = 'Optimization method:';
            app.OptimizationMethodDropDown = uidropdown(app.OptimizationsetupTab);
            app.OptimizationMethodDropDown.Items = {'Nelder-Mead optimization', 'Particle swarm optimization', 'Genetic algorithm'};
            app.OptimizationMethodDropDown.Value = 'Nelder-Mead optimization';
            app.MinimizedobjectivefunctionDropDownLabel = uilabel(app.OptimizationsetupTab);
            app.MinimizedobjectivefunctionDropDownLabel.Text = 'Minimized objective function:';
            app.MinimizedObjectiveFunctionDropDown = uidropdown(app.OptimizationsetupTab);
            app.MinimizedObjectiveFunctionDropDown.Items = {'ISE', 'IAE', 'ITAE', 'ITSE', 'SSE', 'SSTE', 'SST2E', 'Custom objective function'};
            app.MinimizedObjectiveFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @MinimizedObjectiveFunctionDropDownValueChanged, true);
            app.MinimizedObjectiveFunctionDropDown.Value = 'ISE';
            app.CustomObjectiveFunctionPanel = uipanel(app.OptimizationsetupTab);
            app.CustomObjectiveFunctionPanel.Title = 'Custom objective function';
            app.CustomObjectiveFunctionLabel1 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel1.Text = 'Provide an invocation of a custom objective function or short code which returns objective function value.';
            app.CustomObjectiveFunctionLabel2 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel2.Text = 'The function (or provided short code) can only use the following set of arguments/variables: ';
            app.CustomObjectiveFunctionLabel3 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel3.Text = '- errorVector - vector of control error values,';
            app.CustomObjectiveFunctionLabel4 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel4.Text = '- setpointValue - value of the setpoint,';
            app.CustomObjectiveFunctionLabel5 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel5.Text = '- timeVector - vector of time values.';
            app.CustomObjectiveFunctionEditField = uieditfield(app.CustomObjectiveFunctionPanel, 'text');
            app.CustomObjectiveFunctionEditField.Value = 'trapz(timeVector, errorVector.^2)     % Calculates ISE error';
            app.CustomObjectiveFunctionLabel6 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel6.Text = 'Custom objective function invocation:';
            app.CustomObjectiveFunctionLabel7 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel7.Text = 'Example entries:';
            app.CustomObjectiveFunctionLabel8 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel8.Text = 'myCustomObjectiveFunction(errorVector, setpointValue, timeVector, processOutputVector)';
            app.CustomObjectiveFunctionLabel9 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel9.Text = 'myCustomObjectiveFunction(timeVector, processOutputVector)';
            app.CustomObjectiveFunctionLabel10 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel10.Text = 'trapz(timeVector, timeVector'' .* abs(errorVector))';
            app.CustomObjectiveFunctionLabel11 = uilabel(app.CustomObjectiveFunctionPanel);
            app.CustomObjectiveFunctionLabel11.Text = '- processOutputVector - vector of the closed loop system output,';
            app.MinimizedObjectiveFunctionInfoButton = uibutton(app.OptimizationsetupTab, 'push');
            app.MinimizedObjectiveFunctionInfoButton.ButtonPushedFcn = createCallbackFcn(app, @MinimizedObjectiveFunctionInfoButtonPushed, true);
            app.MinimizedObjectiveFunctionInfoButton.Icon = 'information.png';
            app.MinimizedObjectiveFunctionInfoButton.Text = '';
            app.OptimizedMethodInfoButton = uibutton(app.OptimizationsetupTab, 'push');
            app.OptimizedMethodInfoButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizedMethodInfoButtonPushed, true);
            app.OptimizedMethodInfoButton.Icon = 'information.png';
            app.OptimizedMethodInfoButton.Text = '';
            app.CalculateSelectedObjectiveFunctionButton = uibutton(app.OptimizationsetupTab, 'push');
            app.CalculateSelectedObjectiveFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSelectedObjectiveFunctionButtonPushed, true);
            app.CalculateSelectedObjectiveFunctionButton.Text = 'Calculate selected objective function value for the current system setup';
            app.ParametersSearchingButton = uibutton(app.OptimizationsetupTab, 'push');
            app.ParametersSearchingButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersSearchingButtonPushed, true);
            app.ParametersSearchingButton.Text = 'Parameters searching';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Label_12 = uilabel(app.InfoTab);
            app.Label_12.Text = 'Supported by Polish funds of National Science Center, granted on the basis of decision';
            app.DEC201623BST703686Label = uilabel(app.InfoTab);
            app.DEC201623BST703686Label.Text = 'DEC-2016/23/B/ST7/03686';
            app.ContentTextArea = uitextarea(app.InfoTab);
            app.ContentTextArea.Editable = 'off';
            app.ContentTextArea.Value = {'The toolbox allows to simulate and tune parameters of closed-loop systems with a fractional-variable-order digital PID (FVOPID) controller. The toolboox is purely written in MATLAB and uses predefined Simulink models with closed loop systems, which are adjusted according to the setup selected by the user of the toolbox. Users of the toolbox are encouraged to check, validate and modify the implementation if necessary.'; ' '; 'In the future versions of the toolbox the controller blocks implemented as C-MEX S-functions will be added to improve the performance of calculation of FVOPIDs for long simulations or high controller sampling time. Still for short simulations e.g. up to 1000-1200 samples (e.g. for 10[s]-12[s] of simulation time and sampling time equal to 0.01[s]) the time needed to execute single simulation in Simulink should be almost the same for the current implementation of FVOPID controller as for standard MATLAB discrete PID block (checked with MATLAB 2020a). This is because of the time which Simulink model needs to evaluate the closed loop system simulation by itself which for 1000 samples seems to be significantly higher than the time needed by FVOPID controller to calculate its output.'; 'For 2500 samples the time needed for single FVOPID simulation is 2x longer than for standard PID block, while for 5000 samples the time needed for single simulation is 4x longer. This is why the toolbox (especially parameters searching) is currently intended for the simulations which do not exeed 5000 samples (which means e.g. 50[s] simulation where controller''s sampling time is equal to 0.01[s], or 5[s] simulation with the sampling time set to 0.001[s]). '; ''; 'The toolbox is still under testing, validation and verification process. In case of any problems with the implementation, or bugs discovered (either during toolbox execution or found directly in code) please inform:'; 'Piotr Oziabło'; 'p.oziablo@pb.edu.pl'; ''};
            app.ShowSystemResponseButton = uibutton(app.UIFigure, 'push');
            app.ShowSystemResponseButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSystemResponseButtonPushed, true);
            app.ShowSystemResponseButton.Text = 'Show system response';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = variableOrderDifferentialEquationsSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end